classdef CCEP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SubjectIDDropDownLabel        matlab.ui.control.Label
        SubjectIDDropDown             matlab.ui.control.DropDown
        EnterSubjectIDEditFieldLabel  matlab.ui.control.Label
        EnterSubjectIDEditField       matlab.ui.control.EditField
        ConfirmButton                 matlab.ui.control.Button
        MontagePanel                  matlab.ui.container.Panel
        MontageLabel                  matlab.ui.control.Label
        UploadMontageButton           matlab.ui.control.Button
        FilesPanel                    matlab.ui.container.Panel
        UploadRawFileButton           matlab.ui.control.Button
        Upload_datFileButton          matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        EditFileDropDownLabel         matlab.ui.control.Label
        EditFileDropDown              matlab.ui.control.DropDown
        GoButton                      matlab.ui.control.Button
        FileLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        basedir % Description
        subjdir
        subjs
        chans
        idx
        probes
    end
    
    methods (Access = private)
        
        
        function [] = montageCheck(app)
            [ret, fn] = hasValidMontage(app.subjdir);
            if ~ret
                app.MontageLabel.Text = 'No montage file found. Please upload montage';
                app.chans = [];
                app.idx = [];
                app.probes = [];
                app.Upload_datFileButton.Enable = false;
                app.UploadRawFileButton.Enable = false;
            else
                load(fullfile(app.subjdir, fn), 'chans', 'idx', 'probes');
                app.chans = chans;
                app.idx = idx;
                app.probes = probes;
                app.MontageLabel.Text = sprintf('%d channels over %d probes', ...
                    sum(cellfun(@length, app.chans)), length(app.probes));
                app.Upload_datFileButton.Enable = true;
                app.UploadRawFileButton.Enable = true;
            end
        end
        
        function [ret, fn] = getFolders(app)
            d = dir(app.subjdir);
            d = {d([d.isdir]).name};
            d = d(3:end);
            ret = false(size(d)); fn = cell(size(d));
            for dd = 1:length(d)
                [ret(dd), fn{dd}] = hasValidDataFile(fullfile(app.subjdir, d{dd})); 
            end
            fn = fn(ret);
            ret = d(ret);
            if any(cellfun(@length, fn) > 1)
                error('Only one _dat file allowed per folder')
            else
                fn = cellfun(@(x) x{1}, fn, 'UniformOutput', false);
            end
        end
        
        function [stimpair, t] = getStimInfo(app, ret, fn)
            stimpair = cell(size(ret)); t = zeros(size(ret));
            for ff = 1:length(ret)
                load(fullfile(app.subjdir, ret{ff}, fn{ff}), 'anode', 'cathode')
                stimpair{ff} = unique([cathode anode], 'rows');
                t(ff) = length(cathode);
            end
        end
        
        function [] = dispSubj(app)
            [ret, fn] = app.getFolders();
            if isempty(ret)
                app.UITable.Visible = false;
                app.UITable.Data = [];
                app.FileLabel.Text = 'No valid data file for this subject. Please upload data file.';
                app.Upload_datFileButton.Enable = true;
                app.UploadRawFileButton.Enable = true;
                app.EditFileDropDown.Enable = false;
                app.GoButton.Enable = false;
            else
                [stimpair, t] = app.getStimInfo(ret, fn);
                stimpair_f = cellfun(@app.format_stimpair, stimpair, 'UniformOutput', false);
                tbl = table(ret', fn', stimpair_f', t', 'VariableNames', {'Filename', ...
                    'Valid Data File', 'Stim Pair(s)', 'Trials'});
                app.UITable.Visible = true;
                app.UITable.Data = tbl;
                app.FileLabel.Text = sprintf('Displaying %d valid files', length(t));
                app.EditFileDropDown.Enable = true;
                app.EditFileDropDown.Items = ret';
                app.GoButton.Enable = true;
            end
        end
        
        function stimpair_f = format_stimpair(app, stimpair)
            stimpair_f = num2cell(stimpair, 2);
            stimpair_f = cellfun(@(x) sprintf('%d+, %d-', x(1), x(2)), ...
                stimpair_f, 'UniformOutput', false);
            stimpair_f_sub = cellfun(@(x) sprintf('%s; ', x), stimpair_f(1:end-1), ...
                'UniformOutput', false);
            stimpair_f = [stimpair_f_sub{:} stimpair_f{end}];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, basedir)
            if ~exist('basedir', 'var')
                app.basedir = pwd;
            else
                app.basedir = basedir; 
                cd(app.basedir);
            end
            fn = dir(app.basedir);
            fn = fn([fn.isdir]);
            app.subjs = {fn(3:end).name};
            app.SubjectIDDropDown.Items = [{''} app.subjs {'NEW'}];
        end

        % Value changed function: SubjectIDDropDown
        function SubjectIDDropDownValueChanged(app, event)
            value = app.SubjectIDDropDown.Value;
            if ismember(value, app.subjs)
                app.EnterSubjectIDEditField.Visible = false;
                app.ConfirmButton.Visible = false;
                app.UploadMontageButton.Enable = true;
                app.subjdir = fullfile(app.basedir, value);
                app.montageCheck();
                app.dispSubj();
            elseif isequal(value, 'NEW')
                app.EnterSubjectIDEditField.Visible = true;
                app.ConfirmButton.Visible = true;
                app.ConfirmButton.Enable = false;
                app.MontageLabel.Text = '';
                app.FileLabel.Text = '';
                app.chans = []; app.probes = []; app.idx = [];
                app.subjdir = '';
                app.UITable.Visible = false; app.UITable.Data = [];
                app.Upload_datFileButton.Enable = false;
                app.UploadRawFileButton.Enable = false;
                app.EditFileDropDown.Enable = false;
                app.GoButton.Enable = false;
            else
                app.EnterSubjectIDEditField.Visible = false;
                app.ConfirmButton.Visible = false;
                app.MontageLabel.Text = '';
                app.FileLabel.Text = '';
                app.chans = []; app.probes = []; app.idx = [];
                app.subjdir = '';
                app.UITable.Visible = false; app.UITable.Data = [];
                app.Upload_datFileButton.Enable = false;
                app.UploadRawFileButton.Enable = false;
                app.EditFileDropDown.Enable = false;
                app.GoButton.Enable = false;
            end
        end

        % Value changed function: EnterSubjectIDEditField
        function EnterSubjectIDEditFieldValueChanged(app, event)
            app.ConfirmButton.Enable = true;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            value = app.EnterSubjectIDEditField.Value;
            app.subjs = [app.subjs {value}];
            app.SubjectIDDropDown.Items = [{''} app.subjs {'NEW'}];
            app.SubjectIDDropDown.Value = value;
            app.EnterSubjectIDEditField.Visible = false;
            app.ConfirmButton.Visible = false;
            mkdir(fullfile(app.basedir, value));
            app.EnterSubjectIDEditField.Visible = false;
            app.ConfirmButton.Visible = false;
            app.UploadMontageButton.Enable = true;
            app.subjdir = fullfile(app.basedir, value);
            app.montageCheck();
            app.dispSubj();
        end

        % Button pushed function: UploadMontageButton
        function UploadMontageButtonPushed(app, event)
            [file, path] = uigetfile('*_Montage.mat', 'Choose montage file');
            if file ~= 0
                copyfile(fullfile(path, file), app.subjdir);
                app.montageCheck();
            end
        end

        % Button pushed function: UploadRawFileButton
        function UploadRawFileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Choose raw data file');
            if file ~= 0
                fn = split(file, '.');
                fn = fn{1};
                d = dir(app.subjdir);
                d = {d([d.isdir]).name};
                d = d(3:end);
                if ismember(fn, d)
                    app.FileLabel.Text = sprintf('A folder called %s already exists. Please change data file name', fn);
                else
                    app.FileLabel.Text = 'Making directory...';
                    mkdir(fullfile(app.subjdir, fn));
                    app.FileLabel.Text = 'Copying file...';
                    copyfile(fullfile(path, file), fullfile(app.subjdir, fn));
                    app.FileLabel.Text = 'Converting file...';
                    extract_dat(fullfile(app.subjdir, fn, file), app.idx)
                    app.dispSubj();
                end
            end
        end

        % Button pushed function: Upload_datFileButton
        function Upload_datFileButtonPushed(app, event)
            [file, path] = uigetfile('*_dat.mat', 'Choose _dat file');
            if file ~= 0
                fn = split(file, '_dat.');
                fn = fn{1};
                d = dir(app.subjdir);
                d = {d([d.isdir]).name};
                d = d(3:end);
                if ismember(fn, d)
                    app.FileLabel.Text = sprintf('A folder called %s already exists. Please change data file name', fn);
                else
                    app.FileLabel.Text = 'Making directory...';
                    mkdir(fullfile(app.subjdir, fn));
                    app.FileLabel.Text = 'Copying file...';
                    copyfile(fullfile(path, file), fullfile(app.subjdir, fn));
                    app.dispSubj();
                end
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            filename = app.EditFileDropDown.Value;
            subj = app.SubjectIDDropDown.Value;
            CCEP_file(filename, subj, app.basedir, app.chans, app.probes, app.idx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SubjectIDDropDownLabel = uilabel(app.UIFigure);
            app.SubjectIDDropDownLabel.Text = 'Subject ID';
            app.SubjectIDDropDown = uidropdown(app.UIFigure);
            app.SubjectIDDropDown.Items = {' ', 'NEW'};
            app.SubjectIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectIDDropDownValueChanged, true);
            app.SubjectIDDropDown.Value = ' ';
            app.EnterSubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSubjectIDEditFieldLabel.Visible = 'off';
            app.EnterSubjectIDEditFieldLabel.Text = 'Enter Subject ID';
            app.EnterSubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSubjectIDEditFieldValueChanged, true);
            app.EnterSubjectIDEditField.Visible = 'off';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Visible = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.MontagePanel = uipanel(app.UIFigure);
            app.MontagePanel.Title = 'Montage';
            app.MontageLabel = uilabel(app.MontagePanel);
            app.MontageLabel.Text = '';
            app.UploadMontageButton = uibutton(app.MontagePanel, 'push');
            app.UploadMontageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMontageButtonPushed, true);
            app.UploadMontageButton.Enable = 'off';
            app.UploadMontageButton.Text = 'Upload Montage';
            app.FilesPanel = uipanel(app.UIFigure);
            app.FilesPanel.Title = 'Files';
            app.UploadRawFileButton = uibutton(app.FilesPanel, 'push');
            app.UploadRawFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRawFileButtonPushed, true);
            app.UploadRawFileButton.Enable = 'off';
            app.UploadRawFileButton.Text = 'Upload Raw File';
            app.Upload_datFileButton = uibutton(app.FilesPanel, 'push');
            app.Upload_datFileButton.ButtonPushedFcn = createCallbackFcn(app, @Upload_datFileButtonPushed, true);
            app.Upload_datFileButton.Enable = 'off';
            app.Upload_datFileButton.Text = 'Upload _dat File';
            app.UITable = uitable(app.FilesPanel);
            app.UITable.ColumnName = {'Filename'; 'Valid Data File'; 'Stim Pair(s)'; 'Trials'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.EditFileDropDownLabel = uilabel(app.FilesPanel);
            app.EditFileDropDownLabel.Enable = 'off';
            app.EditFileDropDownLabel.Text = 'Edit File';
            app.EditFileDropDown = uidropdown(app.FilesPanel);
            app.EditFileDropDown.Items = {' '};
            app.EditFileDropDown.Enable = 'off';
            app.EditFileDropDown.Value = ' ';
            app.GoButton = uibutton(app.FilesPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.FileLabel = uilabel(app.FilesPanel);
            app.FileLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCEP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end