classdef CCEP_file < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ManualInspectionTab            matlab.ui.container.Tab
        MI_grid                        matlab.ui.container.GridLayout
        MI_ChAx1                       matlab.ui.control.UIAxes
        MI_Ch1                         matlab.ui.control.StateButton
        MI_ChAx2                       matlab.ui.control.UIAxes
        MI_Ch2                         matlab.ui.control.StateButton
        MI_ChAx3                       matlab.ui.control.UIAxes
        MI_Ch3                         matlab.ui.control.StateButton
        MI_ChAx4                       matlab.ui.control.UIAxes
        MI_Ch4                         matlab.ui.control.StateButton
        MI_ChAx5                       matlab.ui.control.UIAxes
        MI_Ch5                         matlab.ui.control.StateButton
        MI_ChAx6                       matlab.ui.control.UIAxes
        MI_Ch6                         matlab.ui.control.StateButton
        MI_ChAx7                       matlab.ui.control.UIAxes
        MI_Ch7                         matlab.ui.control.StateButton
        MI_ChAx8                       matlab.ui.control.UIAxes
        MI_Ch8                         matlab.ui.control.StateButton
        MI_ChAx9                       matlab.ui.control.UIAxes
        MI_Ch9                         matlab.ui.control.StateButton
        MI_ChAx10                      matlab.ui.control.UIAxes
        MI_Ch10                        matlab.ui.control.StateButton
        MI_ChAx11                      matlab.ui.control.UIAxes
        MI_Ch11                        matlab.ui.control.StateButton
        MI_ChAx12                      matlab.ui.control.UIAxes
        MI_Ch12                        matlab.ui.control.StateButton
        MI_ChAx13                      matlab.ui.control.UIAxes
        MI_Ch13                        matlab.ui.control.StateButton
        MI_ChAx14                      matlab.ui.control.UIAxes
        MI_Ch14                        matlab.ui.control.StateButton
        MI_ChAx15                      matlab.ui.control.UIAxes
        MI_Ch15                        matlab.ui.control.StateButton
        MI_BadCh                       matlab.ui.control.Label
        MI_Left                        matlab.ui.control.Button
        MI_Switch                      matlab.ui.control.Switch
        MI_Right                       matlab.ui.control.Button
        MI_Confirm                     matlab.ui.control.Button
        NoiseToleranceTab              matlab.ui.container.Tab
        NT_grid                        matlab.ui.container.GridLayout
        NT_LF_Panel                    matlab.ui.container.Panel
        NT_LFgrid                      matlab.ui.container.GridLayout
        NT_HP                          matlab.ui.control.CheckBox
        withsignificantinterceptGaugeLabel  matlab.ui.control.Label
        NT_InterceptGauge              matlab.ui.control.LinearGauge
        withsignificantslopeLabel      matlab.ui.control.Label
        NT_SlopeGauge                  matlab.ui.control.LinearGauge
        NT_LineNoise_Panel             matlab.ui.container.Panel
        NT_LineGrid                    matlab.ui.container.GridLayout
        NT_AxLineNoise                 matlab.ui.control.UIAxes
        NT_Notch60                     matlab.ui.control.CheckBox
        NT_Notch120                    matlab.ui.control.CheckBox
        NT_Notch180                    matlab.ui.control.CheckBox
        NT_HF_Panel                    matlab.ui.container.Panel
        NT_HFgrid                      matlab.ui.container.GridLayout
        NT_LowpassCheckBox             matlab.ui.control.CheckBox
        NT_AxHFrms                     matlab.ui.control.UIAxes
        NT_AxHFsum                     matlab.ui.control.UIAxes
        NT_AxHF                        matlab.ui.control.UIAxes
        NT_Confirm                     matlab.ui.control.Button
        ArtifactRemovalTab             matlab.ui.container.Tab
        AR_Grid                        matlab.ui.container.GridLayout
        AR_UploadCSV                   matlab.ui.control.Button
        AR_ProcessCSV                  matlab.ui.control.Button
        AR_NumChAcc                    matlab.ui.control.Label
        AR_Ax                          matlab.ui.control.UIAxes
        AR_Left                        matlab.ui.control.Button
        AR_Right                       matlab.ui.control.Button
        AR_TrialPass                   matlab.ui.control.Label
        AR_ProcessDefault              matlab.ui.control.Button
        AR_ChannelParametersPanel      matlab.ui.container.Panel
        AR_ChGrid                      matlab.ui.container.GridLayout
        BracketRangeminEditFieldLabel  matlab.ui.control.Label
        AR_BRmin                       matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        AR_BRmax                       matlab.ui.control.NumericEditField
        DbscanSwitchLabel              matlab.ui.control.Label
        AR_Dbscan                      matlab.ui.control.Switch
        preEditFieldLabel              matlab.ui.control.Label
        AR_Pre                         matlab.ui.control.NumericEditField
        postEditFieldLabel             matlab.ui.control.Label
        AR_Post                        matlab.ui.control.NumericEditField
        AR_RunCh                       matlab.ui.control.Button
        AR_RevertCh                    matlab.ui.control.Button
        AR_CheckCh                     matlab.ui.control.Button
        AR_AccCh                       matlab.ui.control.StateButton
        AR_SaveCur                     matlab.ui.control.Button
        AR_Confirm                     matlab.ui.control.Button
        ProcessingTab                  matlab.ui.container.Tab
        Pr_Grid                        matlab.ui.container.GridLayout
        Pr_FiltersPanel                matlab.ui.container.Panel
        Pr_FiltersGrid                 matlab.ui.container.GridLayout
        Pr_HP                          matlab.ui.control.CheckBox
        Pr_Notch60                     matlab.ui.control.CheckBox
        Pr_Notch120                    matlab.ui.control.CheckBox
        Pr_Notch180                    matlab.ui.control.CheckBox
        Pr_LP                          matlab.ui.control.CheckBox
        Pr_FilterEx                    matlab.ui.control.Button
        Pr_NF                          matlab.ui.control.CheckBox
        BaselinePr_BaselinePanel       matlab.ui.container.Panel
        Pr_BaselineGrid                matlab.ui.container.GridLayout
        Pr_BaselineEx                  matlab.ui.control.Button
        BaselineEditFieldLabel         matlab.ui.control.Label
        Pr_BaselineMin                 matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        Pr_BaselineMax                 matlab.ui.control.NumericEditField
        Pr_EpochPanel                  matlab.ui.container.Panel
        Pr_EpochGrid                   matlab.ui.container.GridLayout
        Pr_EpochEx                     matlab.ui.control.Button
        PreSliderLabel                 matlab.ui.control.Label
        Pr_EpochPre                    matlab.ui.control.Slider
        PostSliderLabel                matlab.ui.control.Label
        Pr_EpochPost                   matlab.ui.control.Slider
        ReReferencePanel               matlab.ui.container.Panel
        Pr_RRgrid                      matlab.ui.container.GridLayout
        Pr_RRscheme                    matlab.ui.container.ButtonGroup
        Pr_CARmean                     matlab.ui.control.ToggleButton
        Pr_CARmed                      matlab.ui.control.ToggleButton
        Pr_BP                          matlab.ui.control.ToggleButton
        Pr_NoRR                        matlab.ui.control.ToggleButton
        Pr_RRex                        matlab.ui.control.Button
        Pr_PrimarySave                 matlab.ui.control.Button
        Pr_SecondarySave               matlab.ui.control.Button
        FitModelsTab                   matlab.ui.container.Tab
        FM_Grid                        matlab.ui.container.GridLayout
        FM_Ax                          matlab.ui.control.UIAxes
        FM_UploadCSV                   matlab.ui.control.Button
        FM_ProcessCSV                  matlab.ui.control.Button
        FM_Left                        matlab.ui.control.Button
        FM_Right                       matlab.ui.control.Button
        FM_R2                          matlab.ui.control.Label
        FM_AccCh                       matlab.ui.control.StateButton
        FM_NumChAcc                    matlab.ui.control.Label
        FM_Confirm                     matlab.ui.control.Button
        FM_ProcessDefault              matlab.ui.control.Button
        FM_ChannelParametersPanel      matlab.ui.container.Panel
        FM_ChGrid                      matlab.ui.container.GridLayout
        FM_C1                          matlab.ui.control.Label
        FM_C1min                       matlab.ui.control.NumericEditField
        FM_C1max                       matlab.ui.control.NumericEditField
        FM_Min                         matlab.ui.control.Label
        FM_C2max                       matlab.ui.control.NumericEditField
        FM_C2min                       matlab.ui.control.NumericEditField
        FM_C2                          matlab.ui.control.Label
        FM_C3                          matlab.ui.control.Label
        FM_C3min                       matlab.ui.control.NumericEditField
        FM_C3max                       matlab.ui.control.NumericEditField
        FM_C4                          matlab.ui.control.Label
        FM_C4min                       matlab.ui.control.NumericEditField
        FM_C4max                       matlab.ui.control.NumericEditField
        C1PolaritySwitchLabel          matlab.ui.control.Label
        FM_Pol                         matlab.ui.control.Switch
        FM_C1start                     matlab.ui.control.NumericEditField
        FM_C4start                     matlab.ui.control.NumericEditField
        FM_C2start                     matlab.ui.control.NumericEditField
        FM_C3start                     matlab.ui.control.NumericEditField
        FM_Start                       matlab.ui.control.Label
        FM_Max                         matlab.ui.control.Label
        FM_ComponentWidth              matlab.ui.control.Label
        FM_RunCh                       matlab.ui.control.Button
        FM_RevertCh                    matlab.ui.control.Button
        Components14EditFieldLabel     matlab.ui.control.Label
        FM_NComp                       matlab.ui.control.NumericEditField
        FM_Prev                        matlab.ui.control.StateButton
        FM_SaveCur                     matlab.ui.control.Button
        QuantifyTab                    matlab.ui.container.Tab
        Q_Panel                        matlab.ui.container.GridLayout
        Q_QuantSave                    matlab.ui.control.Button
        filenameEditFieldLabel         matlab.ui.control.Label
        Q_Fn                           matlab.ui.control.EditField
        SubjectFileLabel               matlab.ui.control.Label
        UPDATEWINDOWPanel              matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UpdateWindow                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % general subject informationnnn
        SubjDir
        SubjFileDir
        Subj
        
        % montage information
        MontageProbes
        MontageIdx
        MontageBadChans
        MontageGoodChans
        MontageNumChans
        
        % stimulation information
        SAmplitude
        SAnode
        SCathode
        SOnsetsSamps
        SPulseWidth
        
        % data
        RawData
        RawDataCtr
        RawFs
        
        % manual inspection tab
        ManualInsAx
        ManualInsBut
        ManualInsCurPg
        ManualInsNPg
        ManualInsCurLns
        
        % noise inspection tab
        NoiseInsPW
        NoiseInsYP
        NoiseInsSP
        NoiseInsAmpSum
        NoiseInsAmpRMS
        NoiseInsFFT
        
        % filter preferences
        NoiseInsHP
        NoiseInsN
        NoiseInsLP
        NoiseInsNF
        
        % artifact removal tab
        ArtremTable
        ArtremData
        ArtremEpoch
        ArtremMean
        ArtremChck
        ArtremBR
        ArtremDM
        ArtremPP
        ArtremHasBeenChanged
        ArtremAddlTableLine
        ArtremInit = struct;
        ArtremCurIdx
        ArtremAcceptedCh
        
        % filter tab
        FiltData
        FiltEpochData
        FiltBLsub
        FitBLlims
        FiltRRscheme
        
        % processed data
        Data
        TEpoch
        EPmean
        EPmedian
        EPstd
        
        % model fitting tab
        ModelEqn
        ModelData
        ModelTVals
        ModelT10
        ModelR2
        ModelCMin
        ModelCMax
        ModelCStart
        ModelNComp
        ModelPol
        ModelHasBeenChanged
        ModelInit = struct;
        ModelCurIdx
        ModelAcceptedCh
        ModelEPCh
        
    end
    
    methods (Access = private)
        
        function [] = ManualInspectionSetup(app)
            
            app.ManualInsAx = {app.MI_ChAx1, app.MI_ChAx2, app.MI_ChAx3, ...
                app.MI_ChAx4, app.MI_ChAx5, app.MI_ChAx6, app.MI_ChAx7, ...
                app.MI_ChAx8, app.MI_ChAx9, app.MI_ChAx10, app.MI_ChAx11, ...
                app.MI_ChAx12, app.MI_ChAx13, app.MI_ChAx14, app.MI_ChAx15};
            app.ManualInsBut = {app.MI_Ch1, app.MI_Ch2, app.MI_Ch3, ...
                app.MI_Ch4, app.MI_Ch5, app.MI_Ch6, app.MI_Ch7, ...
                app.MI_Ch8, app.MI_Ch9, app.MI_Ch10, app.MI_Ch11, ...
                app.MI_Ch12, app.MI_Ch13, app.MI_Ch14, app.MI_Ch15};
            
            app.RawDataCtr = app.RawData - median(app.RawData(1:(app.SOnsetsSamps(1) - 1000), :));
            
            app.ManualInsCurPg = 1;
            app.ManualInsNPg = ceil(size(app.RawData, 2)/15);

            app.MI_BadCh.Text = sprintf('Bad Channels: %s', app.FormatBadChans);
                        
            app.ManualInspectionPlot;
            
            app.MontageNumChans = size(app.RawData, 2);

        end
        
        function [] = NoiseInspectionSetup(app)
            
            cutoff = fdr(app.NoiseInsYP, .05);
            pct = sum(app.NoiseInsYP(app.MontageGoodChans) <= cutoff)/length(app.MontageGoodChans);
            app.NT_InterceptGauge.Value = pct*100;
            cutoff = fdr(app.NoiseInsSP, .05);
            pct = sum(app.NoiseInsSP(app.MontageGoodChans) <= cutoff)/length(app.MontageGoodChans);
            app.NT_SlopeGauge.Value = pct*100;
            
            plot(app.NT_AxLineNoise, app.NoiseInsPW(1:200, app.MontageGoodChans));
            hold(app.NT_AxLineNoise, 'on')
            plot(app.NT_AxLineNoise, mean(app.NoiseInsPW(1:200, app.MontageGoodChans), 2), 'k', 'LineWidth', 3);
            
            L = length(app.NoiseInsFFT); F = (app.RawFs*(0:(L/2))/L)';
            F_use = F >= 250 & F <= 2000;
            plot(app.NT_AxHF, F(F_use), app.NoiseInsFFT(F_use, app.MontageGoodChans))
            plot(app.NT_AxHF, F(F_use), mean(app.NoiseInsFFT(F_use, app.MontageGoodChans), 2), 'k', 'LineWidth', 3);
            histogram(app.NT_AxHFrms, app.NoiseInsAmpSum, 100, 'EdgeColor', 'k', 'DisplayStyle', ...
                'stairs', 'Normalization', 'probability', 'LineWidth', 2)
            histogram(app.NT_AxHFsum, app.NoiseInsAmpRMS, 100, 'EdgeColor', 'k', 'DisplayStyle', ...
                'stairs', 'Normalization', 'probability', 'LineWidth', 2)
            
        end
        
        function [] = ManualInspectionPlot(app)
            
            if isequal(app.MI_Switch.Value, 'Baseline Only')
                x = round(app.RawFs):(app.SOnsetsSamps(1) - round(app.RawFs));
            else
                x = 1:size(app.RawData, 1);
            end
            
            idx1 = 1 + 15*(app.ManualInsCurPg - 1);
            idxend = min(idx1 + 14, size(app.RawData, 2));
            locidx = idx1:idxend;
            app.ManualInsCurLns = cell(1,length(locidx));
            for ii = 1:length(locidx)
                cla(app.ManualInsAx{ii});
                app.ManualInsAx{ii}.Visible = true;
                app.ManualInsBut{ii}.Visible = true;
                if ismember(locidx(ii), app.MontageBadChans)
                    app.ManualInsCurLns{ii} = plot(app.ManualInsAx{ii}, app.RawDataCtr(x, locidx(ii)), 'r');
                else
                    app.ManualInsCurLns{ii} = plot(app.ManualInsAx{ii}, app.RawDataCtr(x, locidx(ii)), 'k');
                end
                
                app.ManualInsBut{ii}.Text = sprintf('Ch. %d', locidx(ii));
                if ismember(locidx(ii), app.MontageBadChans)
                    app.ManualInsBut{ii}.Value = true;
                else
                    app.ManualInsBut{ii}.Value = false;
                end
            end
            if length(locidx) < 15
                for ii = (length(locidx)+1):15
                    cla(app.ManualInsAx{ii});
                    app.ManualInsAx{ii}.Visible = false;
                    app.ManualInsBut{ii}.Visible = false;
                end
            end
            
            if app.ManualInsCurPg == 1
                app.MI_Left.Enable = false;
            else
                app.MI_Left.Enable = true;
            end
            if app.ManualInsCurPg < app.ManualInsNPg
                app.MI_Right.Enable = true;
            else
                app.MI_Right.Enable = false;
            end
            
        end
        
        function badchans_f = FormatBadChans(app)
            badchans_f = arrayfun(@(x) sprintf('%d, ', x), app.MontageBadChans(1:end-1), ...
                'UniformOutput', false);
            badchans_f = horzcat(badchans_f{:}, num2str(app.MontageBadChans(end)));
        end
        
        function [] = ChangeChanDes(app, n)
            value = app.ManualInsBut{n}.Value;
            ch = n + (15*(app.ManualInsCurPg - 1));
            if value
                app.MontageBadChans = sort(horzcat(app.MontageBadChans, ch));
                app.MontageGoodChans(app.MontageGoodChans == ch) = [];
                set(app.ManualInsCurLns{n}, 'Color', 'r');
            else
                app.MontageBadChans(app.MontageBadChans == ch) = [];
                app.MontageGoodChans = sort(horzcat(app.MontageGoodChans, ch));
                set(app.ManualInsCurLns{n}, 'Color', 'k');
            end
            app.MI_BadCh.Text = sprintf('Bad Channels: %s', app.FormatBadChans);
        end
        
        function [] = ArtremPlot(app)
            cla(app.AR_Ax, 'reset');
            n = app.MontageGoodChans(app.ArtremCurIdx);
            loc_chck = app.ArtremChck(:, n);
            if ~all(isnan(loc_chck))
                loc_nan = isnan(loc_chck);
                loc_chck(loc_nan) = 0; loc_chck = logical(loc_chck);
                if all(~loc_chck)
                    plot(app.AR_Ax, -.001:(1/app.RawFs):.01, ...
                        squeeze(app.ArtremEpoch(:, n, ~loc_nan)), 'r');
                else
                    if any(~loc_chck & ~loc_nan)
                        plot(app.AR_Ax, -.001:(1/app.RawFs):.01, ...
                            squeeze(app.ArtremEpoch(:, n, ~loc_chck & ~loc_nan))', 'r');
                    end
                    hold(app.AR_Ax, 'on')
                    plot(app.AR_Ax, -.001:(1/app.RawFs):.01, ...
                        squeeze(app.ArtremEpoch(:, n, loc_chck)), 'g');
                    plot(app.AR_Ax, -.001:(1/app.RawFs):.01, ...
                        app.ArtremMean(:, n), 'k', 'LineWidth', 2);
                   mini = min(app.ArtremMean(:, n)); maxi = max(app.ArtremMean(:, n));
                   ylim(app.AR_Ax, [mini - abs(.1*mini), maxi + abs(.1*maxi)])
                end
                app.AR_Ax.Title.String = sprintf('Channel %d', n);
                app.AR_BRmin.Value = app.ArtremBR(n, 1);
                app.AR_BRmax.Value = app.ArtremBR(n, 2);
                if app.ArtremDM(n)
                    app.AR_Dbscan.Value = 'corr';
                else
                    app.AR_Dbscan.Value = 'eucl';
                end
                app.AR_Pre.Value = app.ArtremPP(n, 1);
                app.AR_Post.Value = app.ArtremPP(n, 2);
                app.AR_RunCh.Enable = true;
            else
                app.AR_Ax.Title.String = 'Stim Channel - No Traces';
                app.ArtremAcceptedCh(n) = true;
                app.AR_RunCh.Enable = false;
            end
            app.AR_TrialPass.Text = sprintf('%d / %d trials pass', sum(loc_chck), length(loc_chck));
            app.AR_AccCh.Value = app.ArtremAcceptedCh(n);
            if length(app.MontageGoodChans) == 1
                app.AR_Left.Enable = false;
                app.AR_Right.Enable = false;
            elseif app.ArtremCurIdx == 1
                app.AR_Left.Enable = false;
                app.AR_Right.Enable = true;
            elseif n == max(app.MontageGoodChans)
                app.AR_Left.Enable = true;
                app.AR_Right.Enable = false;
            else
                app.AR_Left.Enable = true;
                app.AR_Right.Enable = true;
            end
            app.AR_RevertCh.Enable = app.ArtremHasBeenChanged(app.ArtremCurIdx);
        end
        
        function [] = SaveArtremParams(app)
            if isfile(fullfile(app.SubjFileDir, 'artrem_param.mat'))
                c = dialogbox('This will overwrite a previous artrem parameter file. Proceed or cancel?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                fn = fullfile(app.SubjFileDir, 'artrem_param.mat');
                artrem_table = app.ArtremTable;
                BR = app.ArtremBR; DM = app.ArtremDM; PP = app.ArtremPP; accepted_ch = app.ArtremAcceptedCh;
                save(fn, 'artrem_table', 'accepted_ch', 'BR', 'DM', 'PP');
            end
        end
        
        function [] = SaveArtremData(app)
            if isfile(fullfile(app.SubjFileDir, 'artrem_data.mat'))
                c = dialogbox('This will overwrite a previous artrem data file. Proceed or cancel?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                fn = fullfile(app.SubjFileDir, 'artrem_data.mat');
                data_artrem = app.ArtremData; chck = app.ArtremChck;
                save(fn, 'data_artrem', 'chck', '-v7.3');
            end
        end
        
        function [] = ImplementFilter(app, sos, g)
            n = ceil(size(app.FiltData, 2)/10);
            data_out = nan(size(app.FiltData));
            for ii = 1:n
                loc_range = (1:10) + 10*(ii - 1); 
                loc_range(loc_range > size(app.FiltData, 2)) = [];
                loc_range(ismember(loc_range, app.MontageBadChans)) = [];
                loc_range(ismember(loc_range, app.SAnode) | ...
                    ismember(loc_range, app.SCathode)) = []; % assumption of one stim pair
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {sprintf('Filtering channels %d-%d', ...
                    loc_range(1), loc_range(end))}); pause(0.1);
                data_out(:, loc_range) = filtfilt(sos, g, app.FiltData(:, loc_range));
            end
            app.FiltData = data_out;
        end
        
        function [] = SaveProcessed(app, fn)
            if isfile(fn)
                c = dialogbox('This will overwrite a previous filtered data file. Proceed or cancel?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                data = app.Data; tEpoch = app.TEpoch; amplitude = app.SAmplitude; anode = app.SAnode;
                cathode = app.SCathode; fs = app.RawFs; onsets_samps = app.SOnsetsSamps;
                proc_params.HP = app.NoiseInsHP; proc_params.LP = app.NoiseInsLP; proc_params.N = app.NoiseInsN;
                proc_params.BL = app.FitBLlims; proc_params.RR = app.FiltRRscheme;
                save(fn, 'data', 'tEpoch', 'amplitude', 'anode', 'cathode', 'fs', 'onsets_samps', 'proc_params', '-v7.3')
            end
            
        end
        
        function [] = ModelPlot(app)
            cla(app.FM_Ax, 'reset');
            n = app.ModelEPCh(app.ModelCurIdx);
            loc_chck = app.ArtremChck(:, n);
            plot(app.FM_Ax, app.ModelTVals, app.EPmean(app.ModelT10:end, n), 'k', 'LineWidth', 2);
            hold(app.FM_Ax, 'on');
            loc_r2 = app.ModelR2(n);
            if loc_r2 >= 0.8
                plot(app.FM_Ax, app.ModelTVals, app.ModelData(:, n), 'b', 'LineWidth', 2);
            else
                plot(app.FM_Ax, app.ModelTVals, app.ModelData(:, n), 'r', 'LineWidth', 2);
            end
            app.FM_Ax.Title.String = sprintf('Channel %d', n);
            app.FM_C1min.Value = app.ModelCMin(n, 1);
            app.FM_C2min.Value = app.ModelCMin(n, 2);
            app.FM_C3min.Value = app.ModelCMin(n, 3);
            app.FM_C4min.Value = app.ModelCMin(n, 4);
            app.FM_C1max.Value = app.ModelCMax(n, 1);
            app.FM_C2max.Value = app.ModelCMax(n, 2);
            app.FM_C3max.Value = app.ModelCMax(n, 3);
            app.FM_C4max.Value = app.ModelCMax(n, 4);
            app.FM_C1start.Value = app.ModelCStart(n, 1);
            app.FM_C2start.Value = app.ModelCStart(n, 2);
            app.FM_C3start.Value = app.ModelCStart(n, 3);
            app.FM_C4start.Value = app.ModelCStart(n, 4);
            if app.ModelPol(n)
                app.FM_Pol.Value = '+';
            else
                app.FM_Pol.Value = '-';
            end
            app.FM_NComp.Value = app.ModelNComp(n);
            app.FM_RunCh.Enable = true;
            app.FM_R2.Text = sprintf('R2 = %0.4f', app.ModelR2(n));
            app.FM_AccCh.Value = app.ModelAcceptedCh(n);
            if length(app.ModelEPCh) == 1
                app.FM_Left.Enable = false;
                app.FM_Right.Enable = false;
                app.FM_Prev.Enable = false;
            elseif app.ModelCurIdx == 1
                app.FM_Left.Enable = false;
                app.FM_Right.Enable = true;
                app.FM_Prev.Enable = false;
            elseif n == max(app.ModelEPCh)
                app.FM_Left.Enable = true;
                app.FM_Right.Enable = false;
                app.FM_Prev.Enable = true;
            else
                app.FM_Left.Enable = true;
                app.FM_Right.Enable = true;
                app.FM_Prev.Enable = true;
            end
            app.FM_RevertCh.Enable = app.ModelHasBeenChanged(app.ModelCurIdx);
        end
    
        function [] = SaveModelParams(app)
            if isfile(fullfile(app.SubjFileDir, 'model_param.mat'))
                c = dialogbox('This will overwrite a previous model parameter file. Proceed or cancel?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                fn = fullfile(app.SubjFileDir, 'model_param.mat');
                eqns = app.ModelEqn; cmin = app.ModelCMin; cmax = app.ModelCMax;
                cstart = app.ModelCStart; ncomp = app.ModelNComp; pol = app.ModelPol;
                EPchans = app.ModelEPCh;
                save(fn, 'eqns', 'cmin', 'cmax', 'cstart', 'ncomp', 'pol', 'EPchans');
            end
        end
        
        function [] = SaveModelData(app)
            if isfile(fullfile(app.SubjFileDir, 'model_data.mat'))
                c = dialogbox('This will overwrite a previous model data file. Proceed or cancel?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                fn = fullfile(app.SubjFileDir, 'model_data.mat');
                t = app.ModelTVals; models_fit = app.ModelData;
                r2 = app.ModelR2; 
                save(fn, 't', 'models_fit', 'r2');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, foldername, subj, basedir, probes, idx)
            % save input variables
            app.SubjDir = fullfile(basedir, subj);
            app.SubjFileDir = fullfile(basedir, subj, foldername);
            app.MontageProbes = probes;
            app.MontageIdx = idx;
            app.Subj = subj;
            %% load data %%
            app.SubjectFileLabel.Text = sprintf('Subject %s, File %s...loading', app.Subj, ...
                foldername); app.SubjectFileLabel.FontColor = 'r'; pause(0.1);
            [ret, fn] = hasValidDataFile(app.SubjFileDir);
            if ret
                fn = fn{1};
                if isfile(fullfile(app.SubjFileDir, 'badChans.mat')) && ...
                        isfile(fullfile(app.SubjFileDir, 'noise_vals.mat'))
                    s = load(fullfile(app.SubjFileDir, fn));
                    app.SAmplitude = s.amplitude; app.SAnode = s.anode; app.SCathode = s.cathode;
                    app.RawData = s.data; app.RawFs = s.fs; app.SOnsetsSamps = s.onsets_samps;
                    app.SPulseWidth = s.pulse_width; app.MontageNumChans = size(app.RawData, 2);
                    clearvars s
                    s = load(fullfile(app.SubjFileDir, 'badChans.mat'));
                    app.MontageBadChans = s.badchans; app.MontageGoodChans = s.goodchans; clearvars s
                    s = load(fullfile(app.SubjFileDir, 'noise_vals.mat'));
                    app.NoiseInsPW = s.pw_data; app.NoiseInsYP = s.yp; app.NoiseInsSP = s.sp; 
                    app.NoiseInsAmpSum = s.ampsum; app.NoiseInsAmpRMS = s.amprms; app.NoiseInsFFT = s.fft_vals;
                    clearvars s
                    if isfile(fullfile(app.SubjFileDir, 'manualFilterOpts.mat'))
                        s = load(fullfile(app.SubjFileDir, 'manualFilterOpts.mat'));
                        app.NoiseInsHP = s.HP; app.NoiseInsN = s.N; app.NoiseInsLP = s.LP;
                        clearvars s
                        app.NT_HP.Value = app.NoiseInsHP; app.NT_LowpassCheckBox.Value = app.NoiseInsLP;
                        app.NT_Notch60.Value = app.NoiseInsN(1); app.NT_Notch120.Value = app.NoiseInsN(2);
                        app.NT_Notch180.Value = app.NoiseInsN(3);
                        app.Pr_HP.Value = app.NoiseInsHP; app.Pr_LP.Value = app.NoiseInsLP;
                        app.Pr_Notch60.Value = app.NoiseInsN(1); app.Pr_Notch120.Value = app.NoiseInsN(2);
                        app.Pr_Notch180.Value = app.NoiseInsN(3);
                        if sum ([app.NoiseInsHP app.NoiseInsLP app.NoiseInsN]) == 0
                            app.Pr_NF.Value = true;
                            app.NoiseInsNF = true;
                        else
                            app.NoiseInsNF = false;
                        end
                        app.TabGroup.SelectedTab = app.ArtifactRemovalTab;
                    end
                    if isfile(fullfile(app.SubjFileDir, 'artrem_data.mat'))
                        s = load(fullfile(app.SubjFileDir, 'artrem_data.mat'));
                        app.ArtremData = s.data_artrem;
                        app.ArtremEpoch = epochData(app.ArtremData, app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
                        app.ArtremChck = s.chck; loc_chck = app.ArtremChck;
                        loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
                        app.ArtremMean = nan(size(app.ArtremEpoch, 1), size(app.ArtremEpoch, 2));
                        for ii = 1:size(app.ArtremEpoch, 2)
                            loc = squeeze(app.ArtremEpoch(:, ii, :));
                            app.ArtremMean(:, ii) = nanmean(loc(:, loc_chck(:, ii)), 2);
                        end
                        s = load(fullfile(app.SubjFileDir, 'artrem_param.mat'));
                        app.ArtremTable = s.artrem_table; 
                        app.ArtremBR = s.BR; app.ArtremDM = s.DM; app.ArtremPP = s.PP; app.ArtremAcceptedCh = s.accepted_ch;
                        app.ArtremHasBeenChanged = false(size(app.ArtremDM));
                        app.ArtremInit.artrem_table = app.ArtremTable;
                        app.ArtremInit.data_artrem = app.ArtremData;
                        app.ArtremInit.artrem_epoch = app.ArtremEpoch;
                        app.ArtremInit.artrem_mean = app.ArtremMean;
                        app.ArtremInit.chck = app.ArtremChck;
                        app.ArtremInit.BR = app.ArtremBR;
                        app.ArtremInit.DM = app.ArtremDM;
                        app.ArtremInit.PP = app.ArtremPP;
                        app.ArtremCurIdx = 1; app.ArtremPlot();
                        app.Pr_FilterEx.Enable = true;
                        clearvars s
                        app.TabGroup.SelectedTab = app.ProcessingTab;
                    else
                        app.ArtremInit.artrem_table = [];
                        app.ArtremInit.data_artrem = [];
                        app.ArtremInit.artrem_epoch = [];
                        app.ArtremInit.artrem_mean = [];
                        app.ArtremInit.chck = [];
                        app.ArtremInit.BR = [];
                        app.ArtremInit.DM = [];
                        app.ArtremInit.PP = [];
                    end
                    if isfile(fullfile(app.SubjFileDir, 'data_filt.mat')) % if only param file?
                        s = load(fullfile(app.SubjFileDir, 'data_filt.mat'), 'data', 'proc_params', 'tEpoch');
                        app.Data = s.data; app.TEpoch = s.tEpoch;
                        app.Pr_EpochPre.Value = app.TEpoch(1); app.Pr_EpochPost.Value = app.TEpoch(end);
                        app.NoiseInsHP = s.proc_params.HP; app.Pr_HP.Value = app.NoiseInsHP;
                        app.NoiseInsN = s.proc_params.N; app.Pr_Notch60.Value = app.NoiseInsN(1);
                        app.Pr_Notch120.Value = app.NoiseInsN(2); app.Pr_Notch180.Value = app.NoiseInsN(3);
                        app.NoiseInsLP = s.proc_params.LP; app.Pr_LP.Value = app.NoiseInsLP;
                        app.NoiseInsNF = sum([app.NoiseInsHP app.NoiseInsLP app.NoiseInsN]) == 0;
                        app.Pr_NF.Value = app.NoiseInsNF;
                        app.FitBLlims = s.proc_params.BL; app.Pr_BaselineMin.Value = app.FitBLlims(1);
                        app.Pr_BaselineMin.Value = app.FitBLlims(2);
                        app.FiltRRscheme = s.proc_params.RR;
                        switch app.FiltRRscheme
                            case 'mean'
                                app.Pr_CARmean.Value = true; app.Pr_CARmed.Value = false;
                                app.Pr_BP.Value = false; app.Pr_NoRR.Value = false;
                            case 'median'
                                app.Pr_CARmean.Value = false; app.Pr_CARmed.Value = true;
                                app.Pr_BP.Value = false; app.Pr_NoRR.Value = false;
                            case 'bipolar'
                                app.Pr_CARmean.Value = false; app.Pr_CARmed.Value = false;
                                app.Pr_BP.Value = true; app.Pr_NoRR.Value = false;
                            case 'none'
                                app.Pr_CARmean.Value = false; app.Pr_CARmed.Value = false;
                                app.Pr_BP.Value = false; app.Pr_NoRR.Value = true;
                        end
                        app.TabGroup.SelectedTab = app.FitModelsTab;
                        app.EPmean = nan(size(app.Data, 1), size(app.Data, 2));
                        app.EPstd = app.EPmean; app.EPmedian = app.EPmedian;
                        for ii = 1:app.MontageNumChans
                            loc_chck = app.ArtremChck(:, ii);
                            loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
                            loc_dat = squeeze(app.Data(:, ii, :));
                            app.EPmean(:, ii) = nanmean(loc_dat(:, loc_chck), 2);
                            app.EPmedian(:, ii) = nanmedian(loc_dat(:, loc_chck), 2);
                            app.EPstd(:, ii) = nanstd(loc_dat(:, loc_chck), [], 2);
                        end
                    end
                    if isfile(fullfile(app.SubjFileDir, 'model_data.mat'))
                        s = load(fullfile(app.SubjFileDir, 'model_data.mat'));
                        app.ModelTVals = s.t; app.ModelData = s.models_fit; app.ModelR2 = s.r2;
                        app.ModelT10 = find(app.TEpoch >= 0.01);
                        s = load(fullfile(app.SubjFileDir, 'model_param.mat'));
                        app.ModelEqn = s.eqns; app.ModelCMin = s.cmin; app.ModelCMax = s.cmax;
                        app.ModelCStart = s.cstart; app.ModelNComp = s.ncomp; app.ModelPol = s.pol;
                        app.ModelEPCh = s.EPchans; app.ModelCurIdx = 1; 
                        app.ModelAcceptedCh = nan(1, app.MontageNumChans); 
                        app.ModelAcceptedCh(app.ModelEPCh) = true;
                        app.ModelInit.Eqn = app.ModelEqn; app.ModelInit.R2 = app.ModelR2; app.ModelInit.Data = app.ModelData;
                        app.ModelInit.CMin = app.ModelCMin; app.ModelInit.CMax = app.ModelCMax; app.ModelInit.CStart = app.ModelCStart;
                        app.ModelInit.NComp = app.ModelNComp; app.ModelInit.Pol = app.ModelPol;
                        app.ModelHasBeenChanged = false(size(app.ModelEPCh));
                    end
                else
                    [app.SAmplitude, app.SAnode, app.SCathode, app.RawData, app.RawFs, app.SOnsetsSamps, app.SPulseWidth, ...
                        app.MontageBadChans, app.MontageGoodChans, app.NoiseInsPW, app.NoiseInsYP, app.NoiseInsSP, app.NoiseInsAmpSum, app.NoiseInsAmpRMS, app.NoiseInsFFT] = ...
                        newFileCalcs(app.SubjFileDir, fn);
                end
                app.TabGroup.Visible = true;
                app.ManualInspectionSetup;
                app.NoiseInspectionSetup;
                app.SubjectFileLabel.Text = sprintf('Subject %s, File %s', app.Subj, foldername);
                app.SubjectFileLabel.FontColor = 'k';
            else
                error('No file found');
            end
        end

        % Button pushed function: MI_Right
        function MI_RightButtonPushed(app, event)
            app.ManualInsCurPg = app.ManualInsCurPg + 1;
            app.ManualInspectionPlot;
        end

        % Button pushed function: MI_Left
        function MI_LeftButtonPushed(app, event)
            app.ManualInsCurPg = app.ManualInsCurPg - 1;
            app.ManualInspectionPlot;
        end

        % Value changed function: MI_Switch
        function MI_SwitchValueChanged(app, event)
            app.ManualInspectionPlot;            
        end

        % Button pushed function: MI_Confirm
        function MI_ConfirmPushed(app, event)
            badchans = app.MontageBadChans;
            goodchans = app.MontageGoodChans;
            save(fullfile(app.SubjFileDir, 'badChans.mat'), 'badchans', 'goodchans');
            app.TabGroup.SelectedTab = app.NoiseToleranceTab;
        end

        % Button pushed function: NT_Confirm
        function NT_ConfirmPushed(app, event)
            app.NoiseInsHP = app.NT_HP.Value;
            app.NoiseInsN = [app.NT_Notch60.Value, app.NT_Notch120.Value, app.NT_Notch180.Value];
            app.NoiseInsLP = app.NT_LowpassCheckBox.Value;
            if sum ([app.NoiseInsHP app.NoiseInsLP app.NoiseInsN]) == 0
                app.NoiseInsNF = true;
            else
                app.NoiseInsNF = false;
            end
            HP = app.NoiseInsHP; N = app.NoiseInsN; LP = app.NoiseInsLP; NF = app.NoiseInsNF;
            save(fullfile(app.SubjFileDir, 'manualFilterOpts.mat'), 'HP', 'N', 'LP', 'NF');
            app.TabGroup.SelectedTab = app.ArtifactRemovalTab;
            app.Pr_HP.Value = app.NoiseInsHP; app.Pr_LP.Value = app.NoiseInsLP;
            app.Pr_Notch60.Value = app.NoiseInsN(1); app.Pr_Notch120.Value = app.NoiseInsN(2);
            app.Pr_Notch180.Value = app.NoiseInsN(3);
        end

        % Button pushed function: AR_UploadCSV
        function AR_UploadCSVPushed(app, event)
            [filename, pathname] = uigetfile(fullfile(app.SubjFileDir, '*.csv'));
            if ~isempty(app.ArtremTable)
                c = dialogbox('This will overwrite any changes you have made in current artrem. Continue?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                app.ArtremTable = readtable(fullfile(pathname, filename));
                app.ArtremInit.artrem_table = app.ArtremTable;
                app.AR_ProcessCSV.Enable = true;
            end
        end

        % Button pushed function: AR_ProcessCSV
        function AR_ProcessCSVPushed(app, event)
            if ~isempty(app.ArtremTable) && isempty(app.ArtremInit.artrem_table)
                c = dialogbox('This will overwrite any changes you have made in current artrem. Continue?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                cla(app.AR_Ax); app.AR_ProcessCSV.Enable = false; pause(0.1);
                h = height(app.ArtremTable);
                app.ArtremData = nan(size(app.RawData));
                app.ArtremChck = nan(length(app.SAnode), size(app.RawData, 2));
                app.ArtremBR = zeros(size(app.RawData, 2), 2);
                app.ArtremDM = false(size(app.RawData, 2), 1);
                app.ArtremPP = zeros(size(app.RawData, 2), 2);
                t = app.ArtremInit.artrem_table;
                for ii = 1:h
                    ch_out = str2num(t{ii, 'chans_out'}{1});
                    ch_out(~ismember(ch_out, app.MontageGoodChans)) = [];
                    ch_in = str2num(t{ii, 'chans_in'}{1});
                    allnanch = find(all(isnan(app.RawData), 1));
                    ch_in(ismember(ch_in, allnanch)) = [];
                    if any(~ismember(ch_out, ch_in))
                        error('All out channels must be members of in channels.')
                    end
                    br = t{ii, 'br_min'}:t{ii, 'br_max'};
                    dm = t{ii, 'dist_metric'}{1};
                    pre = t{ii, 'pre'};
                    post = t{ii, 'pre'};
                    if any(isnan([ch_out ch_in]))
                        error('Empty channels');
                    end
                    if isempty(br)
                        br = -6:6;
                    end
                    if isempty(dm) || all(isnan(dm))
                        dm = 'eucl';
                    end
                    if isempty(pre) || isnan(pre)
                        pre = 0.4096;
                    end
                    if isempty(post) || isnan(post)
                        post = 0.4096;
                    end
                    stim_trodes = arrayfun(@(x) find(x == ch_in, 1), [app.SAnode app.SCathode], 'UniformOutput', false);
                    stim_trodes(cellfun(@isempty, stim_trodes)) = {length(ch_in)+1};
                    stim_trodes = cell2mat(stim_trodes);
                    [loc_artrem, ~, ~, ~, ~, loc_chck] = ...
                        analyFunc.template_subtract(app.RawData(:, ch_in), app.UpdateWindow, 'fs', app.RawFs, 'stimChans', ...
                        stim_trodes, 'stimRecord', app.SOnsetsSamps, 'bracketRange', br, ...
                        'distanceMetricDbscan', dm, 'pre', pre, 'post', post);
                    if isequal(ch_out, ch_in)
                        app.ArtremData(:, ch_out) = loc_artrem;
                        app.ArtremChck(:, ch_out) = loc_chck;
                    else
                        out_idx = ismember(ch_in, ch_out);
                        app.ArtremData(:, ch_out) = loc_artrem(:, out_idx);
                        app.ArtremChck(:, ch_out) = loc_chck(:, out_idx);
                    end
                    app.ArtremBR(ch_out, :) = repmat([br(1) br(end)], length(ch_out), 1);
                    if strcmp(dm, 'eucl')
                        dm = false;
                    else
                        dm = true;
                    end
                    app.ArtremDM(ch_out) = dm;
                    app.ArtremPP(ch_out, :) = repmat([pre, post], length(ch_out), 1);
                end
                app.ArtremEpoch = epochData(app.ArtremData, app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
                loc_chck = app.ArtremChck;
                loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
                app.ArtremMean = nan(size(app.ArtremEpoch, 1), size(app.ArtremEpoch, 2));
                for ii = 1:size(app.ArtremEpoch, 2)
                    loc = squeeze(app.ArtremEpoch(:, ii, :));
                    app.ArtremMean(:, ii) = nanmean(loc(:, loc_chck(:, ii)), 2);
                end
                app.ArtremAcceptedCh = false(size(app.ArtremData, 2), 1);
                app.ArtremCurIdx = 1;
                app.ArtremHasBeenChanged = false(size(app.MontageGoodChans));
                app.ArtremAddlTableLine = zeros(size(app.MontageGoodChans));
                app.ArtremPlot();
                app.AR_NumChAcc.Enable = true; app.AR_AccCh.Enable = true;
                app.AR_NumChAcc.Text = sprintf('%d / %d good channels accepted', 0, length(app.MontageGoodChans));
                app.AR_SaveCur.Enable = true;
                app.ArtremInit.data_artrem = app.ArtremData; app.ArtremInit.artrem_table = app.ArtremTable;
                app.ArtremInit.artrem_epoch = app.ArtremEpoch; app.ArtremInit.artrem_mean = app.ArtremMean;
                app.ArtremInit.chck = app.ArtremChck; app.ArtremInit.BR = app.ArtremBR; app.ArtremInit.DM = app.ArtremDM;
                app.ArtremInit.PP = app.ArtremPP;
                app.AR_ProcessCSV.Enable = true;
            end
        end

        % Button pushed function: AR_ProcessDefault
        function AR_ProcessDefaultPushed(app, event)
            if ~isempty(app.ArtremTable)
                c = dialogbox('This will overwrite any changes you have made in current artrem. Continue?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                cla(app.AR_Ax); app.AR_ProcessDefault.Enable = false; pause(0.1);
                bads = false(1, app.MontageNumChans);
                bads(app.MontageBadChans) = true;
                bads = repmat(bads, length(app.SAnode), 1);
                [app.ArtremData, ~, ~, ~, ~, app.ArtremChck] = ...
                    analyFunc.template_subtract(app.RawData, app.UpdateWindow, 'fs', app.RawFs, 'stimChans', ...
                    [app.SCathode app.SAnode], 'stimRecord', app.SOnsetsSamps, 'bads', bads);
                app.ArtremBR = repmat([-6 6], size(app.RawData, 2), 1);
                app.ArtremDM = false(size(app.RawData, 2), 1);
                app.ArtremPP = repmat(0.4096, size(app.RawData, 2), 2);
                app.ArtremEpoch = epochData(app.ArtremData, app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
                loc_chck = app.ArtremChck;
                loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
                app.ArtremMean = nan(size(app.ArtremEpoch, 1), size(app.ArtremEpoch, 2));
                for ii = 1:size(app.ArtremEpoch, 2)
                    loc = squeeze(app.ArtremEpoch(:, ii, :));
                    app.ArtremMean(:, ii) = nanmean(loc(:, loc_chck(:, ii)), 2);
                end
                app.ArtremAcceptedCh = false(size(app.ArtremData, 2), 1);
                app.ArtremCurIdx = 1;
                app.ArtremHasBeenChanged = false(size(app.MontageGoodChans));
                app.ArtremAddlTableLine = zeros(size(app.MontageGoodChans));
                app.ArtremPlot();
                app.AR_NumChAcc.Enable = true; app.AR_AccCh.Enable = true;
                app.AR_NumChAcc.Text = sprintf('%d / %d good channels accepted', 0, length(app.MontageGoodChans));
                chans_in = convertCharsToStrings(sprintf('[1:%d]', size(app.RawData, 2))); chans_out = chans_in;
                br_min = -6; br_max = 6; pre = 0.4096; post = 0.4096; dist_metric = "eucl";
                app.ArtremTable = table(chans_in,chans_out,br_min,br_max, dist_metric, pre, post);
                app.AR_SaveCur.Enable = true;
                app.ArtremInit.data_artrem = app.ArtremData; app.ArtremInit.artrem_table = app.ArtremTable;
                app.ArtremInit.artrem_epoch = app.ArtremEpoch; app.ArtremInit.artrem_mean = app.ArtremMean;
                app.ArtremInit.chck = app.ArtremChck; app.ArtremInit.BR = app.ArtremBR; app.ArtremInit.DM = app.ArtremDM;
                app.ArtremInit.PP = app.ArtremPP;
            end
            app.AR_ProcessDefault.Enable = true;
            app.ArtremHasBeenChanged = false(size(app.MontageGoodChans));
        end

        % Button pushed function: AR_Left
        function AR_LeftButtonPushed(app, event)
            app.ArtremCurIdx = app.ArtremCurIdx - 1;
            app.ArtremPlot();
        end

        % Button pushed function: AR_Right
        function AR_RightButtonPushed(app, event)
            app.ArtremCurIdx = app.ArtremCurIdx + 1;
            app.ArtremPlot();
        end

        % Value changed function: AR_AccCh
        function AR_AccChValueChanged(app, event)
            value = app.AR_AccCh.Value;
            app.ArtremAcceptedCh(app.MontageGoodChans(app.ArtremCurIdx)) = value;
            if value 
                if app.ArtremHasBeenChanged(app.ArtremCurIdx)
                    ch = app.MontageGoodChans(app.ArtremCurIdx);
                    br = app.AR_BRmin.Value:app.AR_BRmax.Value;
                    dm = app.AR_Dbscan.Value;
                    pre = app.AR_Pre.Value;
                    post = app.AR_Post.Value;
                    chans_out = convertCharsToStrings(num2str(ch)); chans_in = chans_out;
                    dist_metric = convertCharsToStrings(dm); br_min = br(1); br_max = br(end);
                    if app.ArtremAddlTableLine(app.ArtremCurIdx) ~= 0
                        app.ArtremTable(app.ArtremAddlTableLine(app.ArtremCurIdx), :) = [];
                    end
                    app.ArtremTable = [app.ArtremTable; ...
                        table(chans_out, chans_in, br_min, br_max, dist_metric, pre, post)];
                    app.ArtremAddlTableLine(app.ArtremCurIdx) = height(app.ArtremTable);
                end
                if app.ArtremCurIdx ~= length(app.MontageGoodChans)
                    app.AR_RightButtonPushed();
                end
            end
            if sum(app.ArtremAcceptedCh) == length(app.MontageGoodChans)
                app.AR_NumChAcc.Text = 'ALL good channels accepted';
                app.AR_Confirm.Enable = true;
            else
                app.AR_NumChAcc.Text = sprintf('%d / %d good channels accepted', ...
                    sum(app.ArtremAcceptedCh), length(app.MontageGoodChans));
                app.AR_Confirm.Enable = false;
            end
            
        end

        % Button pushed function: AR_RunCh
        function AR_RunChPushed(app, event)
            app.AR_AccCh.Value = false;
            if ~isempty(app.ArtremHasBeenChanged)
                app.AR_RevertCh.Enable = false;
                app.ArtremHasBeenChanged(app.ArtremCurIdx) = true;
            end
            cla(app.AR_Ax); app.AR_RunCh.Enable = false; pause(0.1);
            ch = app.MontageGoodChans(app.ArtremCurIdx);
            br = app.AR_BRmin.Value:app.AR_BRmax.Value;
            dm = app.AR_Dbscan.Value;
            pre = app.AR_Pre.Value;
            post = app.AR_Post.Value;
            [app.ArtremData(:, ch), ~, ~, ~, ~, app.ArtremChck(:, ch)] = ...
                analyFunc.template_subtract(app.RawData(:, ch), app.UpdateWindow, 'fs', app.RawFs, 'stimChans', ...
                repmat(2, length(app.SAnode), 2), 'stimRecord', app.SOnsetsSamps, ...
                'bracketRange', br, 'distanceMetricDbscan', dm, 'pre', pre, 'post', post);
            app.ArtremBR(ch, :) = [br(1) br(end)]; app.ArtremPP(ch, :) = [pre post];
            if strcmp(dm, 'eucl')
                dm = false;
            else
                dm = true;
            end
            app.ArtremDM(ch) = dm;
            app.ArtremEpoch(:, ch, :) = epochData(app.ArtremData(:, ch), app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
            loc_chck = app.ArtremChck(:, ch);
            loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
            app.ArtremMean(:, ch) = mean(app.ArtremEpoch(:, ch, loc_chck), 3);
            app.ArtremPlot();
            app.ArtremAcceptedCh(ch) = false;
            app.AR_RevertCh.Enable = true;
        end

        % Button pushed function: AR_SaveCur
        function AR_SaveCurPushed(app, event)
            app.AR_SaveCur.Enable = false; pause(0.1);
            app.SaveArtremParams();
            app.AR_SaveCur.Enable = true;
        end

        % Button pushed function: AR_Confirm
        function AR_ConfirmPushed(app, event)
            app.AR_Confirm.Enable = false;
            app.SaveArtremParams();
            app.SaveArtremData();
            app.TabGroup.SelectedTab = app.ProcessingTab;
            app.Pr_FilterEx.Enable = true;
            app.AR_Confirm.Enable = true;
        end

        % Button pushed function: AR_RevertCh
        function AR_RevertChPushed(app, event)
            app.ArtremHasBeenChanged(app.ArtremCurIdx) = false;
            app.AR_AccCh.Value = false;
            cla(app.AR_Ax); app.AR_RevertCh.Enable = false; pause(0.1);
            ch = app.MontageGoodChans(app.ArtremCurIdx);
            app.ArtremData(:, ch) = app.ArtremInit.data_artrem(:, ch);
            app.ArtremEpoch(:, ch, :) = app.ArtremInit.artrem_epoch(:, ch, :);
            app.ArtremMean(:, ch) = app.ArtremInit.artrem_mean(:, ch);
            app.ArtremChck(:, ch) = app.ArtremInit.chck(:, ch);
            app.ArtremBR(ch, :) = app.ArtremInit.BR(ch, :);
            app.ArtremDM(ch) = app.ArtremInit.DM(ch);
            app.ArtremPP(ch) = app.ArtremInit.PP(ch);
            app.ArtremAcceptedCh(ch) = false;
            app.ArtremPlot();
        end

        % Button pushed function: AR_CheckCh
        function AR_CheckChButtonPushed(app, event)
            cla(app.AR_Ax); pause(0.1);
            ch = app.MontageGoodChans(app.ArtremCurIdx);
            dm = app.AR_Dbscan.Value;
            pre = app.AR_Pre.Value;
            post = app.AR_Post.Value;
            [dataout, allchck, br] = helpFunc.checkAllBr(app.RawData(:, ch), app.RawFs, ...
                app.SAnode, app.SCathode, app.SOnsetsSamps, app.UpdateWindow, 'dmdb', dm, 'pre', pre, 'post', post, 'plotIt', false);
            max_numpass = nan(size(allchck)); idx_numpass = nan(size(allchck));
            for ii = 1:length(br)
                numpass = allchck{ii};
                if ~isempty(numpass)
                    numpass = cellfun(@nansum, numpass);
                    [max_numpass(ii), idx_numpass(ii)] = max(numpass);
                end
            end
            [~, br1_idx] = max(max_numpass);
            br2_idx = idx_numpass(br1_idx);
            br = br{br1_idx}{br2_idx};
            
            app.ArtremData(:, ch) = dataout{br1_idx}{br2_idx};
            app.ArtremChck(:, ch) = allchck{br1_idx}{br2_idx};
            app.ArtremEpoch(:, ch, :) = epochData(app.ArtremData(:, ch), app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
            loc_chck = app.ArtremChck(:, ch);
            loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
            app.ArtremMean(:, ch) = mean(app.ArtremEpoch(:, ch, loc_chck), 3);
            
            app.ArtremBR(ch, :) = [br(1) br(end)]; app.ArtremPP(ch, :) = [pre post];
            if strcmp(dm, 'eucl')
                dm = false;
            else
                dm = true;
            end
            app.ArtremDM(ch) = dm;
            
            app.ArtremAcceptedCh(ch) = false;
            app.ArtremHasBeenChanged(app.ArtremCurIdx) = true;
            app.AR_AccCh.Value = false;
            
            app.ArtremPlot();
            
        end

        % Value changed function: Pr_HP
        function Pr_HPValueChanged(app, event)
            app.NoiseInsHP = app.Pr_HP.Value;
            app.NoiseInsNF = none([app.NoiseInsHP app.n app.NoiseInsLP]);
            app.Pr_NF.Value = app.NoiseInsNF;
        end

        % Value changed function: Pr_LP
        function Pr_LPValueChanged(app, event)
            app.NoiseInsLP = app.Pr_LP.Value;
            app.NoiseInsNF = none([app.NoiseInsHP app.n app.NoiseInsLP]);
            app.Pr_NF.Value = app.NoiseInsNF;
        end

        % Value changed function: Pr_Notch60
        function Pr_Notch60ValueChanged(app, event)
            app.NoiseInsN(1) = app.Pr_Notch60.Value;
            app.NoiseInsNF = none([app.NoiseInsHP app.n app.NoiseInsLP]);
            app.Pr_NF.Value = app.NoiseInsNF;
        end

        % Value changed function: Pr_Notch120
        function Pr_Notch120ValueChanged(app, event)
            app.NoiseInsN(2) = app.Pr_Notch120.Value;
            app.NoiseInsNF = none([app.NoiseInsHP app.n app.NoiseInsLP]);
            app.Pr_NF.Value = app.NoiseInsNF;
        end

        % Value changed function: Pr_Notch180
        function Pr_Notch180ValueChanged(app, event)
            app.NoiseInsN(3) = app.Pr_Notch180.Value;
            app.NoiseInsNF = none([app.NoiseInsHP app.n app.NoiseInsLP]);
            app.Pr_NF.Value = app.NoiseInsNF;
        end

        % Value changed function: Pr_NF
        function Pr_NFValueChanged(app, event)
            value = app.Pr_NF.Value;
            app.NoiseInsNF.Value = value;
            if value
                app.Pr_HP.Value = false; app.Pr_Notch60.Value = false; app.Pr_Notch120.Value = false;
                app.PrNotch_120.Value = false; app.NoiseInsHP = false; app.NoiseInsLP = false; app.NoiseInsN = [false false false];
            end
        end

        % Button pushed function: Pr_FilterEx
        function Pr_FilterExPushed(app, event)
            app.FiltData = app.ArtremData;
            app.Pr_FiltReport.Value = {''}; %app.Pr_FilterEx.Enable = false; pause(0.1);
            if app.NoiseInsHP
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'HIGHPASS'});
                [z, p, k] = butter(8, 0.1/(app.RawFs), 'high');
                [sos, g] = zp2sos(z, p, k);
                app.ImplementFilter(sos, g);
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '});
            end
            if app.NoiseInsN(1)
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'NOTCH 60'});
                [z, p, k] = butter(8, [57 63]/(app.RawFs), 'stop');
                [sos, g] = zp2sos(z, p, k);
                app.ImplementFilter(sos, g);
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '});
            end
            if app.NoiseInsN(2)
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'NOTCH 120'});
                [z, p, k] = butter(8, [117 123]/(app.RawFs), 'stop');
                [sos, g] = zp2sos(z, p, k);
                app.ImplementFilter(sos, g);
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '});
            end
            if app.NoiseInsN(3)
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'NOTCH 180'});
                [z, p, k] = butter(8, [177 183]/(app.RawFs), 'stop');
                [sos, g] = zp2sos(z, p, k);
                app.ImplementFilter(sos, g);
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '});
            end
            if app.NoiseInsLP
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'LOWPASS'});
                [z, p, k] = butter(8, 500/(app.RawFs), 'low');
                [sos, g] = zp2sos(z, p, k);
                app.ImplementFilter(sos, g);
                app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '});
            end
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'Filtering complete!'}, {' '});
            app.Pr_FilterEx.Enable = true;
            app.Pr_EpochEx.Enable = true; app.Pr_EpochPre.Enable = true; app.Pr_EpochPost.Enable = true;
        end

        % Button pushed function: Pr_EpochEx
        function Pr_EpochExButtonPushed(app, event)
            app.Pr_EpochEx.Enable = false; pause(0.1);
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'EPOCHING'});
            [app.FiltEpochData, app.TEpoch] = epochData(app.FiltData, app.SOnsetsSamps, ...
                abs(app.Pr_EpochPre.Value), app.Pr_EpochPost.Value, app.RawFs);
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '}, {'Epoching complete!'}, {' '});
            app.Pr_EpochEx.Enable = true; app.Pr_BaselineEx.Enable = true;
        end

        % Button pushed function: Pr_BaselineEx
        function Pr_BaselineExButtonPushed(app, event)
            app.Pr_BaselineEx.Enable = false; pause(0.1);
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'CORRECTING TO BASELINE'});
            app.FitBLlims = [app.Pr_EpochPre.Value app.Pr_EpochPost.Value];
            app.FiltBLsub = baselineSubtract(app.FiltEpochData, app.TEpoch, app.Pr_EpochPre.Value, app.Pr_EpochPost.Value);
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '}, {'Baseline subtraction and normalization complete!'}, {' '});
            app.Pr_BaselineEx.Enable = true; app.Pr_RRex.Enable = true;
            app.Pr_CARmean.Enable = true; app.Pr_CARmed.Enable = true; app.Pr_BP.Enable = true; app.Pr_NoRR.Enable = true;
        end

        % Button pushed function: Pr_RRex
        function Pr_RRexButtonPushed(app, event)

            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'RE-REFERENCING'});
            app.Pr_RRex.Enable = false; pause(0.1);
            if app.Pr_CARmean.Value
                app.Data = rereference(app.FiltBLsub, app.MontageIdx, app.MontageBadChans, 'mean');
                app.FiltRRscheme = 'mean';
            elseif app.Pr_CARmed.Value
                app.Data = rereference(app.FiltBLsub, app.MontageIdx, app.MontageBadChans, 'median');
                app.FiltRRscheme = 'median';
            elseif app.Pr_BP.Value
                app.Data = rereference(app.FiltBLsub, app.MontageIdx, app.MontageBadChans, 'bipolar');
                app.FiltRRscheme = 'bipolar';
            else
                app.Data = app.FiltBLsub;
                app.FiltRRscheme = 'none';
            end
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '}, {'Re-referencing complete!'}, {' '});
            app.Pr_RRex.Enable = true; app.Pr_PrimarySave.Enable = true;
            app.Pr_SecondarySave.Enable = true;
        end

        % Button pushed function: Pr_PrimarySave
        function Pr_PrimarySaveButtonPushed(app, event)
            
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'SAVING AS PRIMARY'});
            app.Pr_PrimarySave.Enable = false; pause(0.1);
            app.SaveProcessed(fullfile(app.SubjFileDir, 'data_filt.mat'));
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '}, {'Data saved!'}, {' '});
            app.Pr_PrimarySave.Enable = true; app.TabGroup.SelectedTab = app.FitModelsTab;
        end

        % Button pushed function: Pr_SecondarySave
        function Pr_SecondarySaveButtonPushed(app, event)

            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {'SAVING AS SECONDARY'});
            app.Pr_SecondarySave.Enable = false; pause(0.1);
            fspec = fullfile(app.SubjFileDir, '*.mat');
            [file, path] = uiputfile(fspec, 'Choose a location and filename for secondary save');
            app.SaveProcessed(fullfile(path, file));
            app.Pr_FiltReport.Value = vertcat(app.Pr_FiltReport.Value, {' '}, {'Data saved!'}, {' '});
            app.Pr_SecondarySave.Enable = true;
        end

        % Button pushed function: FM_UploadCSV
        function FM_UploadCSVButtonPushed(app, event)
            [filename, pathname] = uigetfile(fullfile(app.SubjFileDir, '*.csv'));
            if ~isempty(app.ModelCMin)
                c = dialogbox('This will overwrite any changes you have made in current models. Continue?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                t = readtable(fullfile(pathname, filename));
                app.ModelCMin = nan(app.MontageNumChans, 4); app.ModelCMax = app.ModelCMin;
                app.ModelCStart = app.ModelCMin;
                app.ModelCMin(:, 1) = table2array(t(:, 'C1min')); 
                app.ModelCMin(:, 2) = table2array(t(:, 'C2min'));
                app.ModelCMin(:, 3) = table2array(t(:, 'C3min')); 
                app.ModelCMin(:, 4) = table2array(t(:, 'C4min'));
                app.ModelCMax(:, 1) = table2array(t(:, 'C1max')); 
                app.ModelCMax(:, 2) = table2array(t(:, 'C2max'));
                app.ModelCMax(:, 3) = table2array(t(:, 'C3max')); 
                app.ModelCMax(:, 4) = table2array(t(:, 'C4max'));
                app.ModelCStart(:, 1) = table2array(t(:, 'C1start')); 
                app.ModelCStart(:, 2) = table2array(t(:, 'C2start'));
                app.ModelCStart(:, 3) = table2array(t(:, 'C3start')); 
                app.ModelCStart(:, 4) = table2array(t(:, 'C4start'));
                app.ModelPol = logical(table2array(t(:, 'pol'))); 
                app.ModelNComp = table2array(t(:, 'ncomp'));
                app.ModelEPCh = find(~(isnan(app.ModelNComp) | app.ModelNComp == 0))';
                app.FM_ProcessCSV.Enable = true;
                app.ModelEPCh
            end
        end

        % Button pushed function: FM_ProcessCSV
        function FM_ProcessCSVButtonPushed(app, event)
            cla(app.FM_Ax); %app.FM_ProcessCSV.Enable = false; pause(0.1);
            app.ModelT10 = find(app.TEpoch > 0.01, 1);
            app.ModelTVals = app.TEpoch(app.ModelT10:end)' - app.TEpoch(app.ModelT10);
            app.ModelEqn = cell(app.MontageNumChans, 1); app.ModelR2 = nan(app.MontageNumChans, 1);
            app.ModelData = nan(length(app.ModelTVals), app.MontageNumChans);
            ct = 1;
            for ii = app.ModelEPCh
                fprintf('Channel %d of %d\n', ct, length(app.ModelEPCh));
                [app.ModelData(:, ii), ~, ~, app.ModelEqn{ii}, app.ModelR2(ii)] = ...
                    componentFit(app.EPmean(:, ii), app.TEpoch, 'cmin', app.ModelCMin(ii, :), 'cmax', app.ModelCMax(ii, :), ...
                    'cstr', app.ModelCStart(ii, :), 'numc', app.ModelNComp(ii), 'pol', app.ModelPol(ii));
                ct = ct + 1;
            end
            app.ModelAcceptedCh = false(app.MontageNumChans, 1);
            app.ModelCurIdx = 1;
            app.ModelHasBeenChanged = false(size(app.ModelEPCh));
            app.ModelPlot();
            app.FM_NumChAcc.Enable = true; app.FM_AccCh.Enable = true;
            app.FM_NumChAcc.Text = sprintf('%d / %d EP channels accepted', 0, length(app.ModelEPCh));
            app.FM_SaveCur.Enable = true;
            app.ModelInit.Eqn = app.ModelEqn; app.ModelInit.R2 = app.ModelR2; app.ModelInit.Data = app.ModelData;
            app.ModelInit.CMin = app.ModelCMin; app.ModelInit.CMax = app.ModelCMax; app.ModelInit.CStart = app.ModelCStart;
            app.ModelInit.NComp = app.ModelNComp; app.ModelInit.Pol = app.ModelPol;
        end

        % Button pushed function: FM_ProcessDefault
        function FM_ProcessDefaultPushed(app, event)
            app.TabGroup.SelectedTab = app.FitModelsTab;
            % % % move to tab startup
            app.EPmean = nan(size(app.Data, 1), size(app.Data, 2));
            app.EPstd = app.EPmean; app.EPmedian = app.EPmedian;
            for ii = 1:app.MontageNumChans
                loc_chck = app.ArtremChck(:, ii);
                loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
                loc_dat = squeeze(app.Data(:, ii, :));
                app.EPmean(:, ii) = nanmean(loc_dat(:, loc_chck), 2);
                app.EPmedian(:, ii) = nanmedian(loc_dat(:, loc_chck), 2);
                app.EPstd(:, ii) = nanstd(loc_dat(:, loc_chck), [], 2);
            end
            % % %
            if ~isempty(app.ModelT10)
                c = dialogbox('This will overwrite any changes you have made in current models. Continue?');
                waitfor(c, 'cont');
                cont = c.cont;
                delete(c);
            else
                cont = true;
            end
            if cont
                cla(app.FM_Ax); %app.FM_ProcessDefault.Enable = false; pause(0.1);
                app.ModelT10 = find(app.TEpoch > 0.01, 1);
                app.ModelEPCh = find(sum(abs(app.EPmean(app.ModelT10:end, :)) > 1) >= app.RawFs*0.1);
                app.ModelTVals = app.TEpoch(app.ModelT10:end)' - app.TEpoch(app.ModelT10);
                app.ModelEqn = cell(app.MontageNumChans, 1); app.ModelR2 = nan(app.MontageNumChans, 1);
                app.ModelData = nan(length(app.ModelTVals), app.MontageNumChans);
                app.ModelNComp = nan(app.MontageNumChans, 1);
                app.ModelPol = false(app.MontageNumChans, 1);
                ct = 1;
                for ii = app.ModelEPCh
                    fprintf('Channel %d of %d\n', ct, length(app.ModelEPCh));
                    [app.ModelData(:, ii), app.ModelNComp(ii), app.ModelPol(ii), app.ModelEqn{ii}, app.ModelR2(ii)] = ...
                        componentFit(app.EPmean(:, ii), app.TEpoch);
                    ct = ct + 1;
                end
                app.ModelCMin = nan(app.MontageNumChans, 4); app.ModelCMax = app.ModelCMin; app.ModelCStart = app.ModelCMin;
                app.ModelCMin(app.ModelEPCh, :) = repmat([0.015 0.015 0.05  0.05], length(app.ModelEPCh), 1);
                app.ModelCMax(app.ModelEPCh, :) = repmat([0.5 0.5 0.5 0.5], length(app.ModelEPCh), 1);
                app.ModelCStart(app.ModelEPCh, :) = repmat([0.05 0.05 0.05 0.05], length(app.ModelEPCh), 1);
                app.ModelAcceptedCh = false(app.MontageNumChans, 1);
                app.ModelCurIdx = 1; app.ModelHasBeenChanged = false(size(app.ModelEPCh));
                app.ModelPlot();
                app.FM_NumChAcc.Enable = true; app.FM_AccCh.Enable = true;
                app.FM_NumChAcc.Text = sprintf('%d / %d EP channels accepted', 0, length(app.ModelEPCh));
                app.FM_SaveCur.Enable = true;
                app.ModelInit.Eqn = app.ModelEqn; app.ModelInit.R2 = app.ModelR2; app.ModelInit.Data = app.ModelData;
                app.ModelInit.CMin = app.ModelCMin; app.ModelInit.CMax = app.ModelCMax; app.ModelInit.CStart = app.ModelCStart;
                app.ModelInit.NComp = app.ModelNComp; app.ModelInit.Pol = app.ModelPol;
            end
            app.AR_ProcessDefault.Enable = true;
            
        end

        % Button pushed function: FM_Right
        function FM_RightButtonPushed(app, event)
            app.ModelCurIdx = app.ModelCurIdx + 1;
            app.FM_Prev.Value = false;
            app.ModelPlot();
        end

        % Button pushed function: FM_Left
        function FM_LeftButtonPushed(app, event)
            app.ModelCurIdx = app.ModelCurIdx - 1;
            app.FM_Prev.Value = false;
            app.ModelPlot();
        end

        % Button pushed function: FM_RunCh
        function FM_RunChButtonPushed(app, event)
            cla(app.FM_Ax); % app.FM_RunCh.Enable = false; pause(0.1);
            ch = app.ModelEPCh(app.ModelCurIdx);
            app.ModelAcceptedCh(ch) = false;
            app.FM_AccCh.Value = false;
            if ~isempty(app.ModelHasBeenChanged)
                app.FM_RevertCh.Enable = false;
                app.ModelHasBeenChanged(app.ModelCurIdx) = true;
            end
            cmin = [app.FM_C1min.Value app.FM_C2min.Value app.FM_C3min.Value app.FM_C4min.Value];
            cmax = [app.FM_C1max.Value app.FM_C2max.Value app.FM_C3max.Value app.FM_C4max.Value];
            cstr = [app.FM_C1start.Value app.FM_C2start.Value app.FM_C3start.Value app.FM_C4start.Value];
            numc = app.FM_NComp.Value; pol = app.FM_Pol.Value;
            if strcmp(pol, '-')
                pol = false;
            else
                pol = true;
            end
            [app.ModelData(:, ch), app.ModelNComp(ch), app.ModelPol(ch), app.ModelEqn{ch}, app.ModelR2(ch)] = ...
                componentFit(app.EPmean(:, ch), app.TEpoch, 'cmin', cmin, 'cmax', cmax, 'cstr', cstr, ...
                'numc', numc, 'pol', pol);
            app.ModelCMin(ch, :) = cmin; app.ModelCMax(ch, :) = cmax; app.ModelCStart(ch, :) = cstr;
            app.ModelPlot();
            app.FM_RevertCh.Enable = true;
        end

        % Button pushed function: FM_RevertCh
        function FM_RevertChButtonPushed(app, event)
            app.ModelHasBeenChanged(app.ModelCurIdx) = false;
            app.FM_AccCh.Value = false;
            cla(app.FM_Ax); % app.FM_RevertCh.Enable = false; pause(0.1);
            ch = app.ModelEPCh(app.ModelCurIdx);
            app.ModelData(:, ch) = app.ModelInit.Data(:, ch);
            app.ModelEqn{ch} = app.ModelInit.Eqn{ch};
            app.ModelR2(ch) = app.ModelInit.R2(ch);
            app.ModelCMin(ch, :) = app.ModelInit.CMin(ch, :);
            app.ModelCMax(ch, :) = app.ModelInit.CMax(ch, :);
            app.ModelCStart(ch, :) = app.ModelInit.CStart(ch, :);
            app.ModelNComp(ch) = app.ModelInit.NComp(ch);
            app.ModelPol(ch) = app.ModelInit.Pol(ch);
            app.ModelAcceptedCh(ch) = false;
            app.ModelPlot();
            app.FM_Prev.Value = false;
        end

        % Value changed function: FM_AccCh
        function FM_AccChValueChanged(app, event)
            value = app.FM_AccCh.Value;
            app.ModelAcceptedCh(app.ModelEPCh(app.ModelCurIdx)) = value;
            if value && app.ModelCurIdx ~= length(app.ModelEPCh)
                app.FM_RightButtonPushed();
            end
            if sum(app.ModelAcceptedCh) == length(app.ModelEPCh)
                app.FM_NumChAcc.Text = 'ALL EP channels accepted';
                app.FM_Confirm.Enable = true;
            else
                app.FM_NumChAcc.Text = sprintf('%d / %d EP channels accepted', ...
                    sum(app.ModelAcceptedCh), length(app.ModelEPCh));
                app.FM_Confirm.Enable = false;
            end
            
        end

        % Value changed function: FM_Prev
        function FM_PrevValueChanged(app, event)
            value = app.FM_Prev.Value;
            if value
                n = app.ModelEPCh(app.ModelCurIdx - 1);
            else
                n = app.ModelEPCh(app.ModelCurIdx);
            end
            app.FM_C1min.Value = app.ModelCMin(n, 1);
            app.FM_C2min.Value = app.ModelCMin(n, 2);
            app.FM_C3min.Value = app.ModelCMin(n, 3);
            app.FM_C4min.Value = app.ModelCMin(n, 4);
            app.FM_C1max.Value = app.ModelCMax(n, 1);
            app.FM_C2max.Value = app.ModelCMax(n, 2);
            app.FM_C3max.Value = app.ModelCMax(n, 3);
            app.FM_C4max.Value = app.ModelCMax(n, 4);
            app.FM_C1start.Value = app.ModelCStart(n, 1);
            app.FM_C2start.Value = app.ModelCStart(n, 2);
            app.FM_C3start.Value = app.ModelCStart(n, 3);
            app.FM_C4start.Value = app.ModelCStart(n, 4);
            if app.ModelPol(n)
                app.FM_Pol.Value = '+';
            else
                app.FM_Pol.Value = '-';
            end
            app.FM_NComp.Value = app.ModelNComp(n);
        end

        % Value changed function: FM_C1max, FM_C1min, FM_C1start, 
        % FM_C2max, FM_C2min, FM_C2start, FM_C3max, FM_C3min, 
        % FM_C3start, FM_C4max, FM_C4min, FM_C4start, FM_NComp, 
        % FM_Pol
        function FM_ValuesChanged(app, event)
            app.FM_Prev.Value = false;
        end

        % Button pushed function: FM_SaveCur
        function FM_SaveCurButtonPushed(app, event)
            app.SaveModelParams();
        end

        % Button pushed function: Q_QuantSave
        function Q_QuantSaveButtonPushed(app, event)
            fn = fullfile(app.SubjFileDir, app.Q_Fn.Value);
            EP_wins = nan(app.MontageNumChans, 5);
            EP_mean_amp = nan(app.MontageNumChans, 4); EP_mean_lat = EP_mean_amp; 
            EP_pktr = EP_mean_amp; EP_mean_RMS = EP_mean_amp;
            EP_median_amp = nan(app.MontageNumChans, 4); EP_median_lat = EP_median_amp; 
            EP_median_compmatch = EP_median_amp; EP_median_RMS = EP_median_amp;
            EP_trial_amp = nan(app.MontageNumChans, 4, size(app.Data, 3));
            EP_trial_lat = EP_trial_amp; EP_trial_RMS = EP_trial_amp; EP_trial_compmatch = EP_trial_amp;
            ct = 1;
            for ch = app.ModelEPCh
                fprintf('Quantifying channel %d of %d', ct, length(app.ModelEPCh));
                [EP_mean_amp(ch, :), EP_mean_lat(ch, :), EP_wins(ch, :), ~, EP_pktr(ch, :)] = ...
                    components_from_models(app.TEpoch, app.EPmean(:, ch), app.ModelEqn{ch}, app.ModelT10);
                EP_mean_RMS(ch, :) = rms_from_wins(app.EPmean(:, ch), EP_wins(ch, :));
                [EP_median_amp(ch, :), EP_median_lat(ch, :), EP_median_compmatch(ch, :)] = ...
                    peaks_from_wins(app.EPmedian(:, ch), EP_wins(ch, :), EP_pktr(ch, :));
                EP_median_RMS(ch, :) = rms_from_wins(app.EPmedian(:, ch), EP_wins(ch, :));
                chck_loc = app.ArtremChck(:, ch); chck_loc(isnan(chck_loc)) = 0;
                chck_loc = logical(chck_loc);
                for trial = find(chck_loc')
                    [EP_trial_amp(ch, :, trial), EP_trial_lat(ch, :, trial), EP_trial_compmatch(ch, :, trial)] = ...
                        peaks_from_wins(app.Data(:, ch, trial), EP_wins(ch, :), EP_pktr(ch, :));
                    EP_trial_RMS(ch, :,  trial) = rms_from_wins(app.Data(:, ch, trial), EP_wins(ch, :));
                end
                ct = ct + 1;
            end
            save(fn, 'EP_*');
        end

        % Button pushed function: FM_Confirm
        function FM_ConfirmButtonPushed(app, event)
            app.SaveModelParams();
            app.SaveModelData();
            app.TabGroup.SelectedTab = app.QuantifyTab;
        end

        % Value changed function: MI_Ch1
        function MI_Ch1ValueChanged(app, event)
            n = 1;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch2
        function MI_Ch2ValueChanged(app, event)
            n = 2;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch3
        function MI_Ch3ValueChanged(app, event)
            n = 3;
            app.ChangeChanDes(n);            
        end

        % Value changed function: MI_Ch4
        function MI_Ch4ValueChanged(app, event)
            n = 4;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch5
        function MI_Ch5ValueChanged(app, event)
            n = 5;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch6
        function MI_Ch6ValueChanged(app, event)
            n = 6;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch7
        function MI_Ch7ValueChanged(app, event)
            n = 7;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch8
        function MI_Ch8ValueChanged(app, event)
            n = 8;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch9
        function MI_Ch9ValueChanged(app, event)
            n = 9;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch10
        function MI_Ch10ValueChanged(app, event)
            n = 10;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch11
        function MI_Ch11ValueChanged(app, event)
            n = 11;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch12
        function MI_Ch12ValueChanged(app, event)
            n = 12;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch13
        function MI_Ch13ValueChanged(app, event)
            n = 13;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch14
        function MI_Ch14ValueChanged(app, event)
            n = 14;
            app.ChangeChanDes(n);
        end

        % Value changed function: MI_Ch15
        function MI_Ch15ValueChanged(app, event)
            n = 15;
            app.ChangeChanDes(n);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ManualInspectionTab = uitab(app.TabGroup);
            app.ManualInspectionTab.Title = 'Manual Inspection';
            app.MI_grid = uigridlayout(app.ManualInspectionTab);
            app.MI_ChAx1 = uiaxes(app.MI_grid);
            title(app.MI_ChAx1, '')
            xlabel(app.MI_ChAx1, '')
            ylabel(app.MI_ChAx1, '')
            app.MI_ChAx1.XTick = [];
            app.MI_ChAx1.YTick = [];
            app.MI_Ch1 = uibutton(app.MI_grid, 'state');
            app.MI_Ch1.ValueChangedFcn = createCallbackFcn(app, @MI_Ch1ValueChanged, true);
            app.MI_Ch1.Text = 'Ch.';
            app.MI_ChAx2 = uiaxes(app.MI_grid);
            title(app.MI_ChAx2, '')
            xlabel(app.MI_ChAx2, '')
            ylabel(app.MI_ChAx2, '')
            app.MI_ChAx2.XTick = [];
            app.MI_ChAx2.YTick = [];
            app.MI_Ch2 = uibutton(app.MI_grid, 'state');
            app.MI_Ch2.ValueChangedFcn = createCallbackFcn(app, @MI_Ch2ValueChanged, true);
            app.MI_Ch2.Text = 'Ch.';
            app.MI_ChAx3 = uiaxes(app.MI_grid);
            title(app.MI_ChAx3, '')
            xlabel(app.MI_ChAx3, '')
            ylabel(app.MI_ChAx3, '')
            app.MI_ChAx3.XTick = [];
            app.MI_ChAx3.YTick = [];
            app.MI_Ch3 = uibutton(app.MI_grid, 'state');
            app.MI_Ch3.ValueChangedFcn = createCallbackFcn(app, @MI_Ch3ValueChanged, true);
            app.MI_Ch3.Text = 'Ch.';
            app.MI_ChAx4 = uiaxes(app.MI_grid);
            title(app.MI_ChAx4, '')
            xlabel(app.MI_ChAx4, '')
            ylabel(app.MI_ChAx4, '')
            app.MI_ChAx4.XTick = [];
            app.MI_ChAx4.YTick = [];
            app.MI_Ch4 = uibutton(app.MI_grid, 'state');
            app.MI_Ch4.ValueChangedFcn = createCallbackFcn(app, @MI_Ch4ValueChanged, true);
            app.MI_Ch4.Text = 'Ch.';
            app.MI_ChAx5 = uiaxes(app.MI_grid);
            title(app.MI_ChAx5, '')
            xlabel(app.MI_ChAx5, '')
            ylabel(app.MI_ChAx5, '')
            app.MI_ChAx5.XTick = [];
            app.MI_ChAx5.YTick = [];
            app.MI_Ch5 = uibutton(app.MI_grid, 'state');
            app.MI_Ch5.ValueChangedFcn = createCallbackFcn(app, @MI_Ch5ValueChanged, true);
            app.MI_Ch5.Text = 'Ch.';
            app.MI_ChAx6 = uiaxes(app.MI_grid);
            title(app.MI_ChAx6, '')
            xlabel(app.MI_ChAx6, '')
            ylabel(app.MI_ChAx6, '')
            app.MI_ChAx6.XTick = [];
            app.MI_ChAx6.YTick = [];
            app.MI_Ch6 = uibutton(app.MI_grid, 'state');
            app.MI_Ch6.ValueChangedFcn = createCallbackFcn(app, @MI_Ch6ValueChanged, true);
            app.MI_Ch6.Text = 'Ch.';
            app.MI_ChAx7 = uiaxes(app.MI_grid);
            title(app.MI_ChAx7, '')
            xlabel(app.MI_ChAx7, '')
            ylabel(app.MI_ChAx7, '')
            app.MI_ChAx7.XTick = [];
            app.MI_ChAx7.YTick = [];
            app.MI_Ch7 = uibutton(app.MI_grid, 'state');
            app.MI_Ch7.ValueChangedFcn = createCallbackFcn(app, @MI_Ch7ValueChanged, true);
            app.MI_Ch7.Text = 'Ch.';
            app.MI_ChAx8 = uiaxes(app.MI_grid);
            title(app.MI_ChAx8, '')
            xlabel(app.MI_ChAx8, '')
            ylabel(app.MI_ChAx8, '')
            app.MI_ChAx8.XTick = [];
            app.MI_ChAx8.YTick = [];
            app.MI_Ch8 = uibutton(app.MI_grid, 'state');
            app.MI_Ch8.ValueChangedFcn = createCallbackFcn(app, @MI_Ch8ValueChanged, true);
            app.MI_Ch8.Text = 'Ch.';
            app.MI_ChAx9 = uiaxes(app.MI_grid);
            title(app.MI_ChAx9, '')
            xlabel(app.MI_ChAx9, '')
            ylabel(app.MI_ChAx9, '')
            app.MI_ChAx9.XTick = [];
            app.MI_ChAx9.YTick = [];
            app.MI_Ch9 = uibutton(app.MI_grid, 'state');
            app.MI_Ch9.ValueChangedFcn = createCallbackFcn(app, @MI_Ch9ValueChanged, true);
            app.MI_Ch9.Text = 'Ch.';
            app.MI_ChAx10 = uiaxes(app.MI_grid);
            title(app.MI_ChAx10, '')
            xlabel(app.MI_ChAx10, '')
            ylabel(app.MI_ChAx10, '')
            app.MI_ChAx10.XTick = [];
            app.MI_ChAx10.YTick = [];
            app.MI_Ch10 = uibutton(app.MI_grid, 'state');
            app.MI_Ch10.ValueChangedFcn = createCallbackFcn(app, @MI_Ch10ValueChanged, true);
            app.MI_Ch10.Text = 'Ch.';
            app.MI_ChAx11 = uiaxes(app.MI_grid);
            title(app.MI_ChAx11, '')
            xlabel(app.MI_ChAx11, '')
            ylabel(app.MI_ChAx11, '')
            app.MI_ChAx11.XTick = [];
            app.MI_ChAx11.YTick = [];
            app.MI_Ch11 = uibutton(app.MI_grid, 'state');
            app.MI_Ch11.ValueChangedFcn = createCallbackFcn(app, @MI_Ch11ValueChanged, true);
            app.MI_Ch11.Text = 'Ch.';
            app.MI_ChAx12 = uiaxes(app.MI_grid);
            title(app.MI_ChAx12, '')
            xlabel(app.MI_ChAx12, '')
            ylabel(app.MI_ChAx12, '')
            app.MI_ChAx12.XTick = [];
            app.MI_ChAx12.YTick = [];
            app.MI_Ch12 = uibutton(app.MI_grid, 'state');
            app.MI_Ch12.ValueChangedFcn = createCallbackFcn(app, @MI_Ch12ValueChanged, true);
            app.MI_Ch12.Text = 'Ch.';
            app.MI_ChAx13 = uiaxes(app.MI_grid);
            title(app.MI_ChAx13, '')
            xlabel(app.MI_ChAx13, '')
            ylabel(app.MI_ChAx13, '')
            app.MI_ChAx13.XTick = [];
            app.MI_ChAx13.YTick = [];
            app.MI_Ch13 = uibutton(app.MI_grid, 'state');
            app.MI_Ch13.ValueChangedFcn = createCallbackFcn(app, @MI_Ch13ValueChanged, true);
            app.MI_Ch13.Text = 'Ch.';
            app.MI_ChAx14 = uiaxes(app.MI_grid);
            title(app.MI_ChAx14, '')
            xlabel(app.MI_ChAx14, '')
            ylabel(app.MI_ChAx14, '')
            app.MI_ChAx14.XTick = [];
            app.MI_ChAx14.YTick = [];
            app.MI_Ch14 = uibutton(app.MI_grid, 'state');
            app.MI_Ch14.ValueChangedFcn = createCallbackFcn(app, @MI_Ch14ValueChanged, true);
            app.MI_Ch14.Text = 'Ch.';
            app.MI_ChAx15 = uiaxes(app.MI_grid);
            title(app.MI_ChAx15, '')
            xlabel(app.MI_ChAx15, '')
            ylabel(app.MI_ChAx15, '')
            app.MI_ChAx15.XTick = [];
            app.MI_ChAx15.YTick = [];
            app.MI_Ch15 = uibutton(app.MI_grid, 'state');
            app.MI_Ch15.ValueChangedFcn = createCallbackFcn(app, @MI_Ch15ValueChanged, true);
            app.MI_Ch15.Text = 'Ch.';
            app.MI_BadCh = uilabel(app.MI_grid);
            app.MI_BadCh.Text = 'Bad Channels: ';
            app.MI_Left = uibutton(app.MI_grid, 'push');
            app.MI_Left.ButtonPushedFcn = createCallbackFcn(app, @MI_LeftButtonPushed, true);
            app.MI_Left.Text = '<';
            app.MI_Switch = uiswitch(app.MI_grid, 'slider');
            app.MI_Switch.Items = {'Full Signal', 'Baseline Only'};
            app.MI_Switch.ValueChangedFcn = createCallbackFcn(app, @MI_SwitchValueChanged, true);
            app.MI_Switch.Value = 'Baseline Only';
            app.MI_Right = uibutton(app.MI_grid, 'push');
            app.MI_Right.ButtonPushedFcn = createCallbackFcn(app, @MI_RightButtonPushed, true);
            app.MI_Right.Text = '>';
            app.MI_Confirm = uibutton(app.MI_grid, 'push');
            app.MI_Confirm.ButtonPushedFcn = createCallbackFcn(app, @MI_ConfirmPushed, true);
            app.MI_Confirm.Text = 'CONFIRM BAD CHANS AND CONTINUE';
            app.NoiseToleranceTab = uitab(app.TabGroup);
            app.NoiseToleranceTab.Title = 'Noise Tolerance';
            app.NT_grid = uigridlayout(app.NoiseToleranceTab);
            app.NT_LF_Panel = uipanel(app.NT_grid);
            app.NT_LF_Panel.Title = 'Low Frequency Noise';
            app.NT_LFgrid = uigridlayout(app.NT_LF_Panel);
            app.NT_HP = uicheckbox(app.NT_LFgrid);
            app.NT_HP.Text = 'Highpass';
            app.withsignificantinterceptGaugeLabel = uilabel(app.NT_LFgrid);
            app.withsignificantinterceptGaugeLabel.Text = '% with significant intercept';
            app.NT_InterceptGauge = uigauge(app.NT_LFgrid, 'linear');
            app.NT_InterceptGauge.MajorTicks = [0 20 40 60 80 100];
            app.NT_InterceptGauge.MajorTickLabels = {''};
            app.withsignificantslopeLabel = uilabel(app.NT_LFgrid);
            app.withsignificantslopeLabel.Text = '% with significant slope';
            app.NT_SlopeGauge = uigauge(app.NT_LFgrid, 'linear');
            app.NT_SlopeGauge.MajorTicks = [0 20 40 60 80 100];
            app.NT_SlopeGauge.MajorTickLabels = {''};
            app.NT_LineNoise_Panel = uipanel(app.NT_grid);
            app.NT_LineNoise_Panel.Title = 'Line Noise';
            app.NT_LineGrid = uigridlayout(app.NT_LineNoise_Panel);
            app.NT_AxLineNoise = uiaxes(app.NT_LineGrid);
            title(app.NT_AxLineNoise, '')
            xlabel(app.NT_AxLineNoise, 'frequency')
            ylabel(app.NT_AxLineNoise, 'dB/Hz')
            app.NT_AxLineNoise.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.NT_Notch60 = uicheckbox(app.NT_LineGrid);
            app.NT_Notch60.Text = 'Notch 60';
            app.NT_Notch120 = uicheckbox(app.NT_LineGrid);
            app.NT_Notch120.Text = 'Notch 120';
            app.NT_Notch180 = uicheckbox(app.NT_LineGrid);
            app.NT_Notch180.Text = 'Notch 180';
            app.NT_HF_Panel = uipanel(app.NT_grid);
            app.NT_HF_Panel.Title = 'High Frequency Noise';
            app.NT_HFgrid = uigridlayout(app.NT_HF_Panel);
            app.NT_LowpassCheckBox = uicheckbox(app.NT_HFgrid);
            app.NT_LowpassCheckBox.Text = 'Lowpass 1000';
            app.NT_AxHFrms = uiaxes(app.NT_HFgrid);
            title(app.NT_AxHFrms, '')
            xlabel(app.NT_AxHFrms, '')
            ylabel(app.NT_AxHFrms, '')
            app.NT_AxHFsum = uiaxes(app.NT_HFgrid);
            title(app.NT_AxHFsum, '')
            xlabel(app.NT_AxHFsum, '')
            ylabel(app.NT_AxHFsum, '')
            app.NT_AxHF = uiaxes(app.NT_HFgrid);
            title(app.NT_AxHF, '')
            xlabel(app.NT_AxHF, '')
            ylabel(app.NT_AxHF, '')
            app.NT_Confirm = uibutton(app.NT_grid, 'push');
            app.NT_Confirm.ButtonPushedFcn = createCallbackFcn(app, @NT_ConfirmPushed, true);
            app.NT_Confirm.Text = 'CONFIRM FILTER CHOICES AND CONTINUE';
            app.ArtifactRemovalTab = uitab(app.TabGroup);
            app.ArtifactRemovalTab.Title = 'Artifact Removal';
            app.AR_Grid = uigridlayout(app.ArtifactRemovalTab);
            app.AR_UploadCSV = uibutton(app.AR_Grid, 'push');
            app.AR_UploadCSV.ButtonPushedFcn = createCallbackFcn(app, @AR_UploadCSVPushed, true);
            app.AR_UploadCSV.Text = 'Upload Artifact Removal Parameter CSV';
            app.AR_ProcessCSV = uibutton(app.AR_Grid, 'push');
            app.AR_ProcessCSV.ButtonPushedFcn = createCallbackFcn(app, @AR_ProcessCSVPushed, true);
            app.AR_ProcessCSV.Enable = 'off';
            app.AR_ProcessCSV.Text = 'Process All From CSV';
            app.AR_NumChAcc = uilabel(app.AR_Grid);
            app.AR_NumChAcc.Enable = 'off';
            app.AR_NumChAcc.Text = '/ channels accepted';
            app.AR_Ax = uiaxes(app.AR_Grid);
            title(app.AR_Ax, 'Ch. ')
            xlabel(app.AR_Ax, '')
            ylabel(app.AR_Ax, '')
            app.AR_Left = uibutton(app.AR_Grid, 'push');
            app.AR_Left.ButtonPushedFcn = createCallbackFcn(app, @AR_LeftButtonPushed, true);
            app.AR_Left.Enable = 'off';
            app.AR_Left.Text = '<';
            app.AR_Right = uibutton(app.AR_Grid, 'push');
            app.AR_Right.ButtonPushedFcn = createCallbackFcn(app, @AR_RightButtonPushed, true);
            app.AR_Right.Enable = 'off';
            app.AR_Right.Text = '>';
            app.AR_TrialPass = uilabel(app.AR_Grid);
            app.AR_TrialPass.Text = '/ trials pass';
            app.AR_ProcessDefault = uibutton(app.AR_Grid, 'push');
            app.AR_ProcessDefault.ButtonPushedFcn = createCallbackFcn(app, @AR_ProcessDefaultPushed, true);
            app.AR_ProcessDefault.Text = 'Process All with Default';
            app.AR_ChannelParametersPanel = uipanel(app.AR_Grid);
            app.AR_ChannelParametersPanel.Title = 'Channel Parameters';
            app.AR_ChGrid = uigridlayout(app.AR_ChannelParametersPanel);
            app.BracketRangeminEditFieldLabel = uilabel(app.AR_ChGrid);
            app.BracketRangeminEditFieldLabel.Text = {'Bracket Range'; 'min'};
            app.AR_BRmin = uieditfield(app.AR_ChGrid, 'numeric');
            app.AR_BRmin.Limits = [-Inf 0];
            app.maxEditFieldLabel = uilabel(app.AR_ChGrid);
            app.maxEditFieldLabel.Text = 'max';
            app.AR_BRmax = uieditfield(app.AR_ChGrid, 'numeric');
            app.AR_BRmax.Limits = [0 Inf];
            app.DbscanSwitchLabel = uilabel(app.AR_ChGrid);
            app.DbscanSwitchLabel.Text = 'Dbscan';
            app.AR_Dbscan = uiswitch(app.AR_ChGrid, 'slider');
            app.AR_Dbscan.Items = {'corr', 'eucl'};
            app.AR_Dbscan.Value = 'corr';
            app.preEditFieldLabel = uilabel(app.AR_ChGrid);
            app.preEditFieldLabel.Text = 'pre';
            app.AR_Pre = uieditfield(app.AR_ChGrid, 'numeric');
            app.AR_Pre.Limits = [0 5];
            app.postEditFieldLabel = uilabel(app.AR_ChGrid);
            app.postEditFieldLabel.Text = 'post';
            app.AR_Post = uieditfield(app.AR_ChGrid, 'numeric');
            app.AR_Post.Limits = [0 5];
            app.AR_RunCh = uibutton(app.AR_ChGrid, 'push');
            app.AR_RunCh.ButtonPushedFcn = createCallbackFcn(app, @AR_RunChPushed, true);
            app.AR_RunCh.Text = 'Run';
            app.AR_RevertCh = uibutton(app.AR_ChGrid, 'push');
            app.AR_RevertCh.ButtonPushedFcn = createCallbackFcn(app, @AR_RevertChPushed, true);
            app.AR_RevertCh.Text = 'Revert';
            app.AR_CheckCh = uibutton(app.AR_ChGrid, 'push');
            app.AR_CheckCh.ButtonPushedFcn = createCallbackFcn(app, @AR_CheckChButtonPushed, true);
            app.AR_CheckCh.Text = {'check'; 'all'; 'BRs'};
            app.AR_AccCh = uibutton(app.AR_Grid, 'state');
            app.AR_AccCh.ValueChangedFcn = createCallbackFcn(app, @AR_AccChValueChanged, true);
            app.AR_AccCh.Enable = 'off';
            app.AR_AccCh.Text = 'ACCEPT CHANNEL';
            app.AR_SaveCur = uibutton(app.AR_Grid, 'push');
            app.AR_SaveCur.ButtonPushedFcn = createCallbackFcn(app, @AR_SaveCurPushed, true);
            app.AR_SaveCur.Enable = 'off';
            app.AR_SaveCur.Text = 'Save Current Version';
            app.AR_Confirm = uibutton(app.AR_Grid, 'push');
            app.AR_Confirm.ButtonPushedFcn = createCallbackFcn(app, @AR_ConfirmPushed, true);
            app.AR_Confirm.Enable = 'off';
            app.AR_Confirm.Text = 'CONFIRM ARTREM AND CONTINUE';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.Pr_Grid = uigridlayout(app.ProcessingTab);
            app.Pr_FiltersPanel = uipanel(app.Pr_Grid);
            app.Pr_FiltersPanel.Title = 'Filters';
            app.Pr_FiltersGrid = uigridlayout(app.Pr_FiltersPanel);
            app.Pr_HP = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_HP.ValueChangedFcn = createCallbackFcn(app, @Pr_HPValueChanged, true);
            app.Pr_HP.Text = 'Highpass 0.1';
            app.Pr_Notch60 = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_Notch60.ValueChangedFcn = createCallbackFcn(app, @Pr_Notch60ValueChanged, true);
            app.Pr_Notch60.Text = 'Notch 60';
            app.Pr_Notch120 = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_Notch120.ValueChangedFcn = createCallbackFcn(app, @Pr_Notch120ValueChanged, true);
            app.Pr_Notch120.Text = 'Notch 120';
            app.Pr_Notch180 = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_Notch180.ValueChangedFcn = createCallbackFcn(app, @Pr_Notch180ValueChanged, true);
            app.Pr_Notch180.Text = 'Notch 180';
            app.Pr_LP = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_LP.ValueChangedFcn = createCallbackFcn(app, @Pr_LPValueChanged, true);
            app.Pr_LP.Text = 'Lowpass 500';
            app.Pr_FilterEx = uibutton(app.Pr_FiltersGrid, 'push');
            app.Pr_FilterEx.ButtonPushedFcn = createCallbackFcn(app, @Pr_FilterExPushed, true);
            app.Pr_FilterEx.Text = 'FILTER';
            app.Pr_NF = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_NF.ValueChangedFcn = createCallbackFcn(app, @Pr_NFValueChanged, true);
            app.Pr_NF.Text = 'No filters';
            app.BaselinePr_BaselinePanel = uipanel(app.Pr_Grid);
            app.BaselinePr_BaselinePanel.Title = 'Baseline Subtraction/Normalization';
            app.Pr_BaselineGrid = uigridlayout(app.BaselinePr_BaselinePanel);
            app.Pr_BaselineEx = uibutton(app.Pr_BaselineGrid, 'push');
            app.Pr_BaselineEx.ButtonPushedFcn = createCallbackFcn(app, @Pr_BaselineExButtonPushed, true);
            app.Pr_BaselineEx.Text = 'SUBTRACT AND NORMALIZE';
            app.BaselineEditFieldLabel = uilabel(app.Pr_BaselineGrid);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.Pr_BaselineMin = uieditfield(app.Pr_BaselineGrid, 'numeric');
            app.Pr_BaselineMin.Value = -0.4;
            app.toEditFieldLabel = uilabel(app.Pr_BaselineGrid);
            app.toEditFieldLabel.Text = 'to';
            app.Pr_BaselineMax = uieditfield(app.Pr_BaselineGrid, 'numeric');
            app.Pr_BaselineMax.Value = -0.1;
            app.Pr_EpochPanel = uipanel(app.Pr_Grid);
            app.Pr_EpochPanel.Title = 'Epoching';
            app.Pr_EpochGrid = uigridlayout(app.Pr_EpochPanel);
            app.Pr_EpochEx = uibutton(app.Pr_EpochGrid, 'push');
            app.Pr_EpochEx.ButtonPushedFcn = createCallbackFcn(app, @Pr_EpochExButtonPushed, true);
            app.Pr_EpochEx.Text = 'EPOCH';
            app.PreSliderLabel = uilabel(app.Pr_EpochGrid);
            app.PreSliderLabel.Text = 'Pre';
            app.Pr_EpochPre = uislider(app.Pr_EpochGrid);
            app.Pr_EpochPre.Limits = [-2 0];
            app.Pr_EpochPre.MajorTicks = [-2 -1.75 -1.5 -1.25 -1 -0.75 -0.5 -0.25 0];
            app.Pr_EpochPre.MinorTicks = [];
            app.Pr_EpochPre.Value = -0.5;
            app.PostSliderLabel = uilabel(app.Pr_EpochGrid);
            app.PostSliderLabel.Text = 'Post';
            app.Pr_EpochPost = uislider(app.Pr_EpochGrid);
            app.Pr_EpochPost.Limits = [0 2];
            app.Pr_EpochPost.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.Pr_EpochPost.MinorTicks = [];
            app.Pr_EpochPost.Value = 1;
            app.ReReferencePanel = uipanel(app.Pr_Grid);
            app.ReReferencePanel.Title = 'Re-Reference';
            app.Pr_RRgrid = uigridlayout(app.ReReferencePanel);
            app.Pr_RRscheme = uibuttongroup(app.Pr_RRgrid);
            app.Pr_RRscheme.Title = 'Scheme';
            app.Pr_CARmean = uitogglebutton(app.Pr_RRscheme);
            app.Pr_CARmean.Text = 'Common Mean';
            app.Pr_CARmed = uitogglebutton(app.Pr_RRscheme);
            app.Pr_CARmed.Text = 'Common Median';
            app.Pr_BP = uitogglebutton(app.Pr_RRscheme);
            app.Pr_BP.Text = 'Bipolar';
            app.Pr_NoRR = uitogglebutton(app.Pr_RRscheme);
            app.Pr_NoRR.Text = 'None';
            app.Pr_NoRR.Value = true;
            app.Pr_RRex = uibutton(app.Pr_RRgrid, 'push');
            app.Pr_RRex.ButtonPushedFcn = createCallbackFcn(app, @Pr_RRexButtonPushed, true);
            app.Pr_RRex.Text = 'REFERENCE';
            app.Pr_PrimarySave = uibutton(app.Pr_Grid, 'push');
            app.Pr_PrimarySave.ButtonPushedFcn = createCallbackFcn(app, @Pr_PrimarySaveButtonPushed, true);
            app.Pr_PrimarySave.Text = 'SAVE AS PRIMARY';
            app.Pr_SecondarySave = uibutton(app.Pr_Grid, 'push');
            app.Pr_SecondarySave.ButtonPushedFcn = createCallbackFcn(app, @Pr_SecondarySaveButtonPushed, true);
            app.Pr_SecondarySave.Text = 'save as secondary';
            app.FitModelsTab = uitab(app.TabGroup);
            app.FitModelsTab.Title = 'Fit Models';
            app.FM_Grid = uigridlayout(app.FitModelsTab);
            app.FM_Ax = uiaxes(app.FM_Grid);
            title(app.FM_Ax, 'Ch. ')
            xlabel(app.FM_Ax, '')
            ylabel(app.FM_Ax, '')
            app.FM_UploadCSV = uibutton(app.FM_Grid, 'push');
            app.FM_UploadCSV.ButtonPushedFcn = createCallbackFcn(app, @FM_UploadCSVButtonPushed, true);
            app.FM_UploadCSV.Text = 'Upload Model Parameter CSV';
            app.FM_ProcessCSV = uibutton(app.FM_Grid, 'push');
            app.FM_ProcessCSV.ButtonPushedFcn = createCallbackFcn(app, @FM_ProcessCSVButtonPushed, true);
            app.FM_ProcessCSV.Enable = 'off';
            app.FM_ProcessCSV.Text = 'Fit All from CSV';
            app.FM_Left = uibutton(app.FM_Grid, 'push');
            app.FM_Left.ButtonPushedFcn = createCallbackFcn(app, @FM_LeftButtonPushed, true);
            app.FM_Left.Enable = 'off';
            app.FM_Left.Text = '<';
            app.FM_Right = uibutton(app.FM_Grid, 'push');
            app.FM_Right.ButtonPushedFcn = createCallbackFcn(app, @FM_RightButtonPushed, true);
            app.FM_Right.Enable = 'off';
            app.FM_Right.Text = '>';
            app.FM_R2 = uilabel(app.FM_Grid);
            app.FM_R2.Text = 'R2 =';
            app.FM_AccCh = uibutton(app.FM_Grid, 'state');
            app.FM_AccCh.ValueChangedFcn = createCallbackFcn(app, @FM_AccChValueChanged, true);
            app.FM_AccCh.Enable = 'off';
            app.FM_AccCh.Text = 'ACCEPT CHANNEL';
            app.FM_NumChAcc = uilabel(app.FM_Grid);
            app.FM_NumChAcc.Enable = 'off';
            app.FM_NumChAcc.Text = '/ channels accepted';
            app.FM_Confirm = uibutton(app.FM_Grid, 'push');
            app.FM_Confirm.ButtonPushedFcn = createCallbackFcn(app, @FM_ConfirmButtonPushed, true);
            app.FM_Confirm.Enable = 'off';
            app.FM_Confirm.Text = 'CONFIRM MODELS AND CONTINUE';
            app.FM_ProcessDefault = uibutton(app.FM_Grid, 'push');
            app.FM_ProcessDefault.ButtonPushedFcn = createCallbackFcn(app, @FM_ProcessDefaultPushed, true);
            app.FM_ProcessDefault.Text = 'Fit All with Defaults';
            app.FM_ChannelParametersPanel = uipanel(app.FM_Grid);
            app.FM_ChannelParametersPanel.Title = 'Channel Parameters';
            app.FM_ChGrid = uigridlayout(app.FM_ChannelParametersPanel);
            app.FM_C1 = uilabel(app.FM_ChGrid);
            app.FM_C1.Text = 'C1';
            app.FM_C1min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C1min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C1max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C1max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_Min = uilabel(app.FM_ChGrid);
            app.FM_Min.Text = 'Min';
            app.FM_C2max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C2max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C2min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C2min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C2 = uilabel(app.FM_ChGrid);
            app.FM_C2.Text = 'C2';
            app.FM_C3 = uilabel(app.FM_ChGrid);
            app.FM_C3.Text = 'C3';
            app.FM_C3min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C3min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C3max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C3max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C4 = uilabel(app.FM_ChGrid);
            app.FM_C4.Text = 'C4';
            app.FM_C4min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C4min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C4max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C4max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.C1PolaritySwitchLabel = uilabel(app.FM_ChGrid);
            app.C1PolaritySwitchLabel.Text = 'C1 Polarity';
            app.FM_Pol = uiswitch(app.FM_ChGrid, 'slider');
            app.FM_Pol.Items = {'-', '+'};
            app.FM_Pol.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_Pol.Value = '-';
            app.FM_C1start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C1start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C4start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C4start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C2start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C2start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C3start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C3start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_Start = uilabel(app.FM_ChGrid);
            app.FM_Start.Text = 'Start';
            app.FM_Max = uilabel(app.FM_ChGrid);
            app.FM_Max.Text = 'Max';
            app.FM_ComponentWidth = uilabel(app.FM_ChGrid);
            app.FM_ComponentWidth.Text = 'Component Width Parameters';
            app.FM_RunCh = uibutton(app.FM_ChGrid, 'push');
            app.FM_RunCh.ButtonPushedFcn = createCallbackFcn(app, @FM_RunChButtonPushed, true);
            app.FM_RunCh.Enable = 'off';
            app.FM_RunCh.Text = 'Run';
            app.FM_RevertCh = uibutton(app.FM_ChGrid, 'push');
            app.FM_RevertCh.ButtonPushedFcn = createCallbackFcn(app, @FM_RevertChButtonPushed, true);
            app.FM_RevertCh.Enable = 'off';
            app.FM_RevertCh.Text = 'Revert';
            app.Components14EditFieldLabel = uilabel(app.FM_ChGrid);
            app.Components14EditFieldLabel.Text = 'Components (1-4)';
            app.FM_NComp = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_NComp.Limits = [1 4];
            app.FM_NComp.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_NComp.Value = 4;
            app.FM_Prev = uibutton(app.FM_ChGrid, 'state');
            app.FM_Prev.ValueChangedFcn = createCallbackFcn(app, @FM_PrevValueChanged, true);
            app.FM_Prev.Enable = 'off';
            app.FM_Prev.Text = 'Use Previous Ch Settings';
            app.FM_SaveCur = uibutton(app.FM_Grid, 'push');
            app.FM_SaveCur.ButtonPushedFcn = createCallbackFcn(app, @FM_SaveCurButtonPushed, true);
            app.FM_SaveCur.Enable = 'off';
            app.FM_SaveCur.Text = 'Save Current Version';
            app.QuantifyTab = uitab(app.TabGroup);
            app.QuantifyTab.Title = 'Quantify';
            app.Q_Panel = uigridlayout(app.QuantifyTab);
            app.Q_QuantSave = uibutton(app.Q_Panel, 'push');
            app.Q_QuantSave.ButtonPushedFcn = createCallbackFcn(app, @Q_QuantSaveButtonPushed, true);
            app.Q_QuantSave.Text = 'QUANTIFY AND SAVE';
            app.filenameEditFieldLabel = uilabel(app.Q_Panel);
            app.filenameEditFieldLabel.Text = 'filename';
            app.Q_Fn = uieditfield(app.Q_Panel, 'text');
            app.SubjectFileLabel = uilabel(app.GridLayout);
            app.SubjectFileLabel.Text = 'Subject, File';
            app.UPDATEWINDOWPanel = uipanel(app.GridLayout);
            app.UPDATEWINDOWPanel.Title = 'UPDATE WINDOW';
            app.GridLayout2 = uigridlayout(app.UPDATEWINDOWPanel);
            app.UpdateWindow = uitextarea(app.GridLayout2);
            app.UpdateWindow.Value = {'-- updates will be printed here --'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCEP_file(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end